classdef InSitu_BST_SoftwareV8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartProcessButton              matlab.ui.control.Button
        OpenFileLocationButton          matlab.ui.control.Button
        FileLoc                         matlab.ui.control.TextArea
        SaveFileLocationLabel           matlab.ui.control.Label
        StopProcessButton               matlab.ui.control.Button
        HPGUI_Group                     matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        NumberoflogpointsduringstressIandTLabel  matlab.ui.control.Label
        Itpoints                        matlab.ui.control.NumericEditField
        StressVoltageVLabel_4           matlab.ui.control.Label
        Bias_gnl                        matlab.ui.control.NumericEditField
        ImpedanceAnalyzerCVLabel_gnl    matlab.ui.control.Label
        Freq_unit_gnl                   matlab.ui.control.DropDown
        FrequencyLabel_4                matlab.ui.control.Label
        Frequency_gnl                   matlab.ui.control.NumericEditField
        PositiveVoltageVLabel_4         matlab.ui.control.Label
        VPos_gnl                        matlab.ui.control.NumericEditField
        NegativeVoltageVLabel_4         matlab.ui.control.Label
        VNeg_gnl                        matlab.ui.control.NumericEditField
        VACmVpkpkEditFieldLabel_4       matlab.ui.control.Label
        OSC_gnl                         matlab.ui.control.NumericEditField
        SweepSwitchLabel_4              matlab.ui.control.Label
        Sweep_gnl                       matlab.ui.control.RockerSwitch
        StepVoltagemVLabel_4            matlab.ui.control.Label
        VStep_gnl                       matlab.ui.control.NumericEditField
        ofAveragesDropDownLabel_4       matlab.ui.control.Label
        NOA_gnl                         matlab.ui.control.DropDown
        IntegrationTimeDropDownLabel_4  matlab.ui.control.Label
        ITM_gnl                         matlab.ui.control.DropDown
        ExperimentalsettingsLabel       matlab.ui.control.Label
        t_inc_unit_gnl                  matlab.ui.control.DropDown
        StresstimeLabel                 matlab.ui.control.Label
        dt_gnl                          matlab.ui.control.NumericEditField
        TotalnumberofcyclesLabel        matlab.ui.control.Label
        Iter_tot_gnl                    matlab.ui.control.NumericEditField
        TemperatureramplogintervalsLabel  matlab.ui.control.Label
        T_log_ramp_step                 matlab.ui.control.NumericEditField
        LogsettingsLabel                matlab.ui.control.Label
        ConnectionPanel                 matlab.ui.container.Panel
        PowerSwitchLabel                matlab.ui.control.Label
        PowerSwitch                     matlab.ui.control.ToggleSwitch
        Hotplate1LampLabel              matlab.ui.control.Label
        StatusLampHP1                   matlab.ui.control.Lamp
        Hotplate2Label                  matlab.ui.control.Label
        StatusLampHP2                   matlab.ui.control.Lamp
        Hotplate3Label                  matlab.ui.control.Label
        StatusLampHP3                   matlab.ui.control.Lamp
        Arduino1Label                   matlab.ui.control.Label
        StatusLampArd1                  matlab.ui.control.Lamp
        Arduino2Label                   matlab.ui.control.Label
        StatusLampArd2                  matlab.ui.control.Lamp
        Arduino3Label                   matlab.ui.control.Label
        StatusLampArd3                  matlab.ui.control.Lamp
        KeithleyLabel                   matlab.ui.control.Label
        StatusLampKeithley              matlab.ui.control.Lamp
        ImpedanceAnalyzerLabel          matlab.ui.control.Label
        StatusLampImpedance             matlab.ui.control.Lamp
        HP1_Tab                         matlab.ui.container.Tab
        HP1_Group                       matlab.ui.container.TabGroup
        Input                           matlab.ui.container.Tab
        ExperimentalFittingLabel_1      matlab.ui.control.Label
        t_offset_unit_1                 matlab.ui.control.DropDown
        THKnmEditFieldLabel             matlab.ui.control.Label
        THK_1                           matlab.ui.control.NumericEditField
        HeatTempCLabel                  matlab.ui.control.Label
        TempH_1                         matlab.ui.control.NumericEditField
        TimeOffsetLabel                 matlab.ui.control.Label
        TimeOffset_1                    matlab.ui.control.NumericEditField
        IterationsMeasureLabel          matlab.ui.control.Label
        IterM_1                         matlab.ui.control.NumericEditField
        PreStressTimeminLabel           matlab.ui.control.Label
        PreBiasTime_1                   matlab.ui.control.NumericEditField
        CoolTempCLabel                  matlab.ui.control.Label
        TempC_1                         matlab.ui.control.NumericEditField
        P1Lamp_1                        matlab.ui.control.Lamp
        P2Lamp_1                        matlab.ui.control.Lamp
        P3Lamp_1                        matlab.ui.control.Lamp
        P4Lamp_1                        matlab.ui.control.Lamp
        P1_1                            matlab.ui.control.StateButton
        P2_1                            matlab.ui.control.StateButton
        P3_1                            matlab.ui.control.StateButton
        P4_1                            matlab.ui.control.StateButton
        ArduinoPinLabel_1               matlab.ui.control.Label
        AP1_1                           matlab.ui.control.NumericEditField
        AP2_1                           matlab.ui.control.NumericEditField
        AP3_1                           matlab.ui.control.NumericEditField
        AP4_1                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2               matlab.ui.control.Label
        P5Lamp_1                        matlab.ui.control.Lamp
        P6Lamp_1                        matlab.ui.control.Lamp
        P7Lamp_1                        matlab.ui.control.Lamp
        P8Lamp_1                        matlab.ui.control.Lamp
        P5_1                            matlab.ui.control.StateButton
        P6_1                            matlab.ui.control.StateButton
        P7_1                            matlab.ui.control.StateButton
        P8_1                            matlab.ui.control.StateButton
        AP5_1                           matlab.ui.control.NumericEditField
        AP6_1                           matlab.ui.control.NumericEditField
        AP7_1                           matlab.ui.control.NumericEditField
        AP8_1                           matlab.ui.control.NumericEditField
        PinStatesLabel_1                matlab.ui.control.Label
        P1Label_1                       matlab.ui.control.Label
        P2Label_1                       matlab.ui.control.Label
        P3Label_1                       matlab.ui.control.Label
        P4Label_1                       matlab.ui.control.Label
        P5Label_1                       matlab.ui.control.Label
        P6Label_1                       matlab.ui.control.Label
        P7Label_1                       matlab.ui.control.Label
        P8Label_1                       matlab.ui.control.Label
        PinReset_1                      matlab.ui.control.Button
        SwitchAllLabel                  matlab.ui.control.Label
        SwitchAll_HP1                   matlab.ui.control.ToggleSwitch
        SaveDataAsTextAreaLabel_2       matlab.ui.control.Label
        DataFileName_MU1                matlab.ui.control.TextArea
        CV1Tab_1                        matlab.ui.container.Tab
        Export_CV_1                     matlab.ui.control.Button
        CV1_1                           matlab.ui.control.UIAxes
        Normalize_1                     matlab.ui.control.StateButton
        CV2_1                           matlab.ui.control.UIAxes
        CV3_1                           matlab.ui.control.UIAxes
        CV4_1                           matlab.ui.control.UIAxes
        CV2Tab_1                        matlab.ui.container.Tab
        Export_CV_2_1                   matlab.ui.control.Button
        CV5_1                           matlab.ui.control.UIAxes
        Normalize_2_1                   matlab.ui.control.StateButton
        CV6_1                           matlab.ui.control.UIAxes
        CV7_1                           matlab.ui.control.UIAxes
        CV8_1                           matlab.ui.control.UIAxes
        VFBFit1Tab_1                    matlab.ui.container.Tab
        byC2_1_1                        matlab.ui.control.UIAxes
        byC2_2_1                        matlab.ui.control.UIAxes
        byC2_3_1                        matlab.ui.control.UIAxes
        byC2_4_1                        matlab.ui.control.UIAxes
        Export_byC2_1                   matlab.ui.control.Button
        DerPeaks_1_1                    matlab.ui.control.StateButton
        UpdateFit_1                     matlab.ui.control.Button
        CFitMinEditFieldLabel           matlab.ui.control.Label
        CFitMin_1                       matlab.ui.control.NumericEditField
        FitRegion_1                     matlab.ui.control.StateButton
        SmoothCheckBox_1                matlab.ui.control.CheckBox
        CFitMaxEditFieldLabel           matlab.ui.control.Label
        CFitMax_1                       matlab.ui.control.NumericEditField
        VFBFit2Tab_1                    matlab.ui.container.Tab
        byC2_5_1                        matlab.ui.control.UIAxes
        byC2_6_1                        matlab.ui.control.UIAxes
        byC2_7_1                        matlab.ui.control.UIAxes
        byC2_8_1                        matlab.ui.control.UIAxes
        Export_byC2_2_1                 matlab.ui.control.Button
        DerPeaks_1_2                    matlab.ui.control.StateButton
        SmoothCheckBox_2_1              matlab.ui.control.CheckBox
        UpdateFit_2_1                   matlab.ui.control.Button
        FitRegion_2_1                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_2         matlab.ui.control.Label
        CFitMax_2_1                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_2         matlab.ui.control.Label
        CFitMin_2_1                     matlab.ui.control.NumericEditField
        ItTab_1                         matlab.ui.container.Tab
        It_1                            matlab.ui.control.UIAxes
        Export_It_1                     matlab.ui.control.Button
        VFBvsTimeTab_1                  matlab.ui.container.Tab
        ExportVFBtime_1                 matlab.ui.control.Button
        VFBtime_1                       matlab.ui.control.UIAxes
        TempvsTimeTab_1                 matlab.ui.container.Tab
        TempTime_1                      matlab.ui.control.UIAxes
        Export_TempTime_1               matlab.ui.control.Button
        HP2_Tab                         matlab.ui.container.Tab
        HP1_Group_2                     matlab.ui.container.TabGroup
        Input_2                         matlab.ui.container.Tab
        ExperimentalFittingLabel_2      matlab.ui.control.Label
        t_offset_unit_2                 matlab.ui.control.DropDown
        THKnmEditFieldLabel_4           matlab.ui.control.Label
        THK_2                           matlab.ui.control.NumericEditField
        HeatTempCLabel_4                matlab.ui.control.Label
        TempH_2                         matlab.ui.control.NumericEditField
        TimeOffsetLabel_4               matlab.ui.control.Label
        TimeOffset_2                    matlab.ui.control.NumericEditField
        IterationsMeasureLabel_4        matlab.ui.control.Label
        IterM_2                         matlab.ui.control.NumericEditField
        PreStressTimeminLabel_4         matlab.ui.control.Label
        PreBiasTime_2                   matlab.ui.control.NumericEditField
        CoolTempCLabel_4                matlab.ui.control.Label
        TempC_2                         matlab.ui.control.NumericEditField
        P1Lamp_2                        matlab.ui.control.Lamp
        P2Lamp_2                        matlab.ui.control.Lamp
        P3Lamp_2                        matlab.ui.control.Lamp
        P4Lamp_2                        matlab.ui.control.Lamp
        P1_2                            matlab.ui.control.StateButton
        P2_2                            matlab.ui.control.StateButton
        P3_2                            matlab.ui.control.StateButton
        P4_2                            matlab.ui.control.StateButton
        ArduinoPinLabel_1_2             matlab.ui.control.Label
        AP1_2                           matlab.ui.control.NumericEditField
        AP2_2                           matlab.ui.control.NumericEditField
        AP3_2                           matlab.ui.control.NumericEditField
        AP4_2                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2_2             matlab.ui.control.Label
        P5Lamp_2                        matlab.ui.control.Lamp
        P6Lamp_2                        matlab.ui.control.Lamp
        P7Lamp_2                        matlab.ui.control.Lamp
        P8Lamp_2                        matlab.ui.control.Lamp
        P5_2                            matlab.ui.control.StateButton
        P6_2                            matlab.ui.control.StateButton
        P7_2                            matlab.ui.control.StateButton
        P8_2                            matlab.ui.control.StateButton
        AP5_2                           matlab.ui.control.NumericEditField
        AP6_2                           matlab.ui.control.NumericEditField
        AP7_2                           matlab.ui.control.NumericEditField
        AP8_2                           matlab.ui.control.NumericEditField
        PinStatesLabel_2                matlab.ui.control.Label
        P1Label_2                       matlab.ui.control.Label
        P2Label_2                       matlab.ui.control.Label
        P3Label_2                       matlab.ui.control.Label
        P4Label_2                       matlab.ui.control.Label
        P5Label_2                       matlab.ui.control.Label
        P6Label_2                       matlab.ui.control.Label
        P7Label_2                       matlab.ui.control.Label
        P8Label_2                       matlab.ui.control.Label
        PinReset_2                      matlab.ui.control.Button
        SwitchAllLabel_4                matlab.ui.control.Label
        SwitchAll_HP2                   matlab.ui.control.ToggleSwitch
        SaveDataAsTextAreaLabel_1_2     matlab.ui.control.Label
        DataFileName_MU2                matlab.ui.control.TextArea
        CV1Tab_2                        matlab.ui.container.Tab
        Export_CV_2                     matlab.ui.control.Button
        CV1_2                           matlab.ui.control.UIAxes
        Normalize_2                     matlab.ui.control.StateButton
        CV2_2                           matlab.ui.control.UIAxes
        CV3_2                           matlab.ui.control.UIAxes
        CV4_2                           matlab.ui.control.UIAxes
        CV2Tab_2                        matlab.ui.container.Tab
        Export_CV_2_2                   matlab.ui.control.Button
        CV5_2                           matlab.ui.control.UIAxes
        Normalize_2_2                   matlab.ui.control.StateButton
        CV6_2                           matlab.ui.control.UIAxes
        CV7_2                           matlab.ui.control.UIAxes
        CV8_2                           matlab.ui.control.UIAxes
        VFBFit1Tab_2                    matlab.ui.container.Tab
        byC2_1_2                        matlab.ui.control.UIAxes
        byC2_2_2                        matlab.ui.control.UIAxes
        byC2_3_2                        matlab.ui.control.UIAxes
        byC2_4_2                        matlab.ui.control.UIAxes
        Export_byC2_2                   matlab.ui.control.Button
        DerPeaks_2_1                    matlab.ui.control.StateButton
        UpdateFit_2                     matlab.ui.control.Button
        CFitMinEditFieldLabel_7         matlab.ui.control.Label
        CFitMin_2                       matlab.ui.control.NumericEditField
        FitRegion_2                     matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_7         matlab.ui.control.Label
        CFitMax_2                       matlab.ui.control.NumericEditField
        SmoothCheckBox_1_2              matlab.ui.control.CheckBox
        VFBFit2Tab_2                    matlab.ui.container.Tab
        byC2_5_2                        matlab.ui.control.UIAxes
        byC2_6_2                        matlab.ui.control.UIAxes
        byC2_7_2                        matlab.ui.control.UIAxes
        byC2_8_2                        matlab.ui.control.UIAxes
        Export_byC2_2_2                 matlab.ui.control.Button
        DerPeaks_2_2                    matlab.ui.control.StateButton
        SmoothCheckBox_2_2              matlab.ui.control.CheckBox
        UpdateFit_2_2                   matlab.ui.control.Button
        FitRegion_2_4                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_8         matlab.ui.control.Label
        CFitMax_2_2                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_8         matlab.ui.control.Label
        CFitMin_2_2                     matlab.ui.control.NumericEditField
        ItTab_2                         matlab.ui.container.Tab
        It_2                            matlab.ui.control.UIAxes
        Export_It_2                     matlab.ui.control.Button
        VFBvsTimeTab_2                  matlab.ui.container.Tab
        ExportVFBtime_2                 matlab.ui.control.Button
        VFBtime_2                       matlab.ui.control.UIAxes
        TempvsTimeTab_2                 matlab.ui.container.Tab
        TempTime_2                      matlab.ui.control.UIAxes
        Export_TempTime_2               matlab.ui.control.Button
        HP3_Tab                         matlab.ui.container.Tab
        HP3_Group                       matlab.ui.container.TabGroup
        Input_3                         matlab.ui.container.Tab
        ExperimentalFittingLabel_3      matlab.ui.control.Label
        t_offset_unit_3                 matlab.ui.control.DropDown
        THKnmEditFieldLabel_3           matlab.ui.control.Label
        THK_3                           matlab.ui.control.NumericEditField
        HeatTempCLabel_3                matlab.ui.control.Label
        TempH_3                         matlab.ui.control.NumericEditField
        TimeOffsetLabel_3               matlab.ui.control.Label
        TimeOffset_3                    matlab.ui.control.NumericEditField
        IterationsMeasureLabel_3        matlab.ui.control.Label
        IterM_3                         matlab.ui.control.NumericEditField
        PreStressTimeminLabel_3         matlab.ui.control.Label
        PreBiasTime_3                   matlab.ui.control.NumericEditField
        CoolTempCLabel_3                matlab.ui.control.Label
        TempC_3                         matlab.ui.control.NumericEditField
        P1Lamp_3                        matlab.ui.control.Lamp
        P2Lamp_3                        matlab.ui.control.Lamp
        P3Lamp_3                        matlab.ui.control.Lamp
        P4Lamp_3                        matlab.ui.control.Lamp
        P1_3                            matlab.ui.control.StateButton
        P2_3                            matlab.ui.control.StateButton
        P3_3                            matlab.ui.control.StateButton
        P4_3                            matlab.ui.control.StateButton
        ArduinoPinLabel_1_3             matlab.ui.control.Label
        AP1_3                           matlab.ui.control.NumericEditField
        AP2_3                           matlab.ui.control.NumericEditField
        AP3_3                           matlab.ui.control.NumericEditField
        AP4_3                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2_3             matlab.ui.control.Label
        P5Lamp_3                        matlab.ui.control.Lamp
        P6Lamp_3                        matlab.ui.control.Lamp
        P7Lamp_3                        matlab.ui.control.Lamp
        P8Lamp_3                        matlab.ui.control.Lamp
        P5_3                            matlab.ui.control.StateButton
        P6_3                            matlab.ui.control.StateButton
        P7_3                            matlab.ui.control.StateButton
        P8_3                            matlab.ui.control.StateButton
        AP5_3                           matlab.ui.control.NumericEditField
        AP6_3                           matlab.ui.control.NumericEditField
        AP7_3                           matlab.ui.control.NumericEditField
        AP8_3                           matlab.ui.control.NumericEditField
        PinStatesLabel_3                matlab.ui.control.Label
        P1Label_3                       matlab.ui.control.Label
        P2Label_3                       matlab.ui.control.Label
        P3Label_3                       matlab.ui.control.Label
        P4Label_3                       matlab.ui.control.Label
        P5Label_3                       matlab.ui.control.Label
        P6Label_3                       matlab.ui.control.Label
        P7Label_3                       matlab.ui.control.Label
        P8Label_3                       matlab.ui.control.Label
        PinReset_3                      matlab.ui.control.Button
        SwitchAllLabel_3                matlab.ui.control.Label
        SwitchAll_HP3                   matlab.ui.control.ToggleSwitch
        SaveDataAsTextAreaLabel_3       matlab.ui.control.Label
        DataFileName_MU3                matlab.ui.control.TextArea
        CV1Tab_3                        matlab.ui.container.Tab
        Export_CV_1_3                   matlab.ui.control.Button
        CV1_3                           matlab.ui.control.UIAxes
        Normalize_1_3                   matlab.ui.control.StateButton
        CV2_3                           matlab.ui.control.UIAxes
        CV3_3                           matlab.ui.control.UIAxes
        CV4_3                           matlab.ui.control.UIAxes
        CV2Tab_3                        matlab.ui.container.Tab
        Export_CV_2_3                   matlab.ui.control.Button
        CV5_3                           matlab.ui.control.UIAxes
        Normalize_2_3                   matlab.ui.control.StateButton
        CV6_3                           matlab.ui.control.UIAxes
        CV7_3                           matlab.ui.control.UIAxes
        CV8_3                           matlab.ui.control.UIAxes
        VFBFit1Tab_3                    matlab.ui.container.Tab
        byC2_1_3                        matlab.ui.control.UIAxes
        byC2_2_3                        matlab.ui.control.UIAxes
        byC2_3_3                        matlab.ui.control.UIAxes
        byC2_4_3                        matlab.ui.control.UIAxes
        Export_byC2_1_3                 matlab.ui.control.Button
        DerPeaks_3_1                    matlab.ui.control.StateButton
        UpdateFit_1_3                   matlab.ui.control.Button
        CFitMinEditFieldLabel_5         matlab.ui.control.Label
        CFitMin_1_3                     matlab.ui.control.NumericEditField
        FitRegion_1_3                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_5         matlab.ui.control.Label
        CFitMax_1_3                     matlab.ui.control.NumericEditField
        SmoothCheckBox_5                matlab.ui.control.CheckBox
        VFBFit2Tab_3                    matlab.ui.container.Tab
        byC2_5_3                        matlab.ui.control.UIAxes
        byC2_6_3                        matlab.ui.control.UIAxes
        byC2_7_3                        matlab.ui.control.UIAxes
        byC2_8_3                        matlab.ui.control.UIAxes
        Export_byC2_2_3                 matlab.ui.control.Button
        DerPeaks_3_2                    matlab.ui.control.StateButton
        SmoothCheckBox_6                matlab.ui.control.CheckBox
        UpdateFit_2_3                   matlab.ui.control.Button
        FitRegion_2_3                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_6         matlab.ui.control.Label
        CFitMax_2_3                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_6         matlab.ui.control.Label
        CFitMin_5                       matlab.ui.control.NumericEditField
        ItTab_3                         matlab.ui.container.Tab
        It_3                            matlab.ui.control.UIAxes
        Export_It_3                     matlab.ui.control.Button
        VFBvsTimeTab_3                  matlab.ui.container.Tab
        ExportVFBtime_3                 matlab.ui.control.Button
        VFBtime_3                       matlab.ui.control.UIAxes
        TempvsTimeTab_3                 matlab.ui.container.Tab
        TempTime_3                      matlab.ui.control.UIAxes
        Export_TempTime_3               matlab.ui.control.Button
        SaveDataAsTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        dir % Description
        dtime % Description
        I % Description
        HP % Description
        Therm % Description
        TC % Description
        TC_time % Description
        It_t % Description
        P % Description
        S % Description
        HW = 0; % Structure containing hardware handles
        stopFlag = 0; % A flag indicating that all measurements need to be stopped
        idleFlag = 1; % A flag indicating that the system is idle and ready for running measurements
        loopInterval = 5; % The period with which the timeLoop will call fcnCallback
        devicesConnected = 0; % A flag indicating if all devices are connected/disconnected
    end
    
    methods (Access = public)
        
        function CloseHP(app, HW, MUnb, COM) %Function to Close/Clear Hotplate Serial Object
            CloseHP_ext(app, HW, MUnb, COM);
        end
        
        function val = tInSec(app,UnitDrop,property) %Function to Convert Any Time Value to Seconds From Specified GUI Unit
            if (UnitDrop == "sec")
                val = property; % sec to sec
            elseif (UnitDrop == "min")
                val = 60*property; % min to sec
            elseif (UnitDrop == "hrs")
                val = 60*60*property; % hrs to sec
            elseif (UnitDrop == "days")
                val = 24*60*60*property; % hrs to sec
            end
        end
        
        function [Cpr,Rpr,Vr] = RunProgCV(app, Prog) %Function to Run CV Progam/Measurement
            fprintf(v,Prog); %Print/Send program to Impedance Analyzer
            out=textscan(query(v, 'RUN'),'%c %s %s %s %s %s %s %s','headerlines', 4); % Run Program and Wait for Data Output
            fprintf(v,"FNC2"); %Gain-Phase Function (Switches DC Bias Off, by Going to Different Measurement Mode)
            
            unit = cell2mat(out{1,5}); %Analyzes unit factor from Impedance Anlyzer (IA) output
            mult = []; %Set multiplication factor to null
            
            for j=1:length(unit) %Parse through unit factor from output and define multiplacation factor array
                if (unit(j) == "f")
                    mult = [mult 1e-15 ];
                elseif (unit(j) == "p")
                    mult = [mult 1e-12 ];
                elseif (unit(j) == "n")
                    mult = [mult 1e-9 ];
                elseif (unit(i) == "u")
                    mult = [mult 1e-6 ];
                elseif (unit(i) == "m")
                    mult = [mult 1e-3 ];
                end
            end
            
            Cpr =str2double(out{1,4}); %Copy out capacitance (C) double array from (IA) output
            Cpr = Cpr(1:end-1).*mult'; %Multiplat C array with corresponding unit multiplication factor
            
            Vr = str2double(out{1,3}); %Copy out voltatge (V) double array from (IA) output
            Vr = Vr(1:end-1); %Remove character tail
            
            Rpr =str2double(out{1,6})*1e3; %Copy out resistance (R) double array from (IA) output (mult to get ohms)
            Rpr = Rpr(1:end-1); %Remove character tail
        end
        
        %Function to Run DC Stress Bias using Keithley 2401
        function [I t TempTC temp_t] = RunBias(app, Vstress,tIstress,tstep,th,Ih,PinState,ArdP,LampSet,LampColor,IsPreBias,PreBiasPin)
            [I t TempTC temp_t] = RunBias_ext(app, Vstress,tIstress,tstep,th,Ih,PinState,ArdP,LampSet,LampColor,IsPreBias,PreBiasPin)
        end
        
        function [Cfit,V,Vfb,fitfail,FitRegion_1] = VFBfitN(app, Cp,V,m,byC2Plot) %Flatband Linear Fitting Function
            Cfit = 1./(Cp./max(Cp)).^2; %Define normallized 1/C^2
            Vfbfit = [];
            Vfb = [];
            
            fitfail = 0; %Set fit boolean to 0 (Fit hasn't failed yet)
            in1 = find((V(:,1)<4)); %Concatenate data before 4V (Assuming Vfb is below this)
            V = V(in1,:); %Concatenate data before 4V (Assuming Vfb is below this)
            Cfit = Cfit(in1,:); %Concatenate data before 4V (Assuming Vfb is below this)
            
            FitRegion_1 = [];
            set(app.byC2_1_1, 'ColorOrder', jet(2*(app.Iter_tot_1.Value+1))) %Set fit plot colororder
            for j = 1:m %For every measurement m
                %Define region to fit based on Cmin & Cmax input
                in2 = find((Cfit(:,j)<app.CFitMax_1.Value & Cfit(:,j)>app.CFitMin_1.Value));
                Vfbfit = V(in2,j); %Limit voltage to fitting range
                %Cfit(:,i) = smooth(Cfit(:,i));
                try
                    f = fit(Vfbfit,Cfit(in2,j),'poly1'); %Fit 1/C^2 vs. V curve with polynomial fit
                    CfbFit = feval(f,V(:,j)); %Evaluate fit
                    plot(byC2Plot,V(:,j),Cfit(:,j),'LineWidth',1.5); %Plot 1/C^2 vs. Voltage
                    hold(byC2Plot,'on')
                    plot(byC2Plot,V(:,j),CfbFit, 'k --','LineWidth',1.5); %Plot fitted polynomial line
                    Vfb =[-f.p2/f.p1 Vfb ]; %Get flatband from y-intercept
                catch
                    fitfail = 1; %Set fitfail boolean to true (fit has failed)
                    hold(app.byC2_1_1,'off');
                    display("Couldn't fit for Flatband Voltage")
                end
            end
            app.byC2_1_1.YLim = [0 app.CFitMax_1.Value*2]
            hold(app.byC2_1_1,'off')
        end
        
        function PlotCV(app, C,V,Plot) %Function to plot CV curve & temperaure
            PlotCV_ext(app, C,V,Plot);
        end
        
        function Success = setHPTemp(app,HP,T) %Function to set hotplate temperature
            %Set temperature devided into to values high temp (HT) & low temp (LT)
            %To be read in by hotplate
            
            HT = floor(T/25.6); %Set high temp value
            LT = mod(T,25.6)*10; %Set low temp value
            
            CumSum = 178+HT+LT; %Obtain checksum value
            if (CumSum > 255) %If checksum is more than hexadecimal limit (255)
                CumSum = CumSum-256; %Checksum needs to be reset by subtracting 256
            end
            
            arry = [254,178,HT,LT,0, CumSum]; %Serial bit/byte to send to hotplate
            for j=1:length(arry) %Iterature through serial array with 50ms waitime
                fwrite(HP,arry(j),'uint8'); %Write to hotplate
                pause(.05)
            end
            out = fread(HP); %Read out hotplate response
            
            if isempty(out) %If not responce
                display("Temperature Set Not Successful")
                Success = 0;
            else
                Success = 1;
                display("Temperature Set!")
            end
        end
        
        function [TR,SR,TS,SS,out] =  getHPParam(app,HP) %Function to read out parameters from hotplate (not currently used)
            arry = [254,162,0,0,0,162]; %Read hexadecimal byte serial command array
            fwrite(HP,[254,162,0,0,0,162],'uint8') %Write read command
            out = (fread(HP))';
            
            SS = [];
            SR = [];
            TS = [];
            TR = [];
            
            if isempty(out) %If no response
                display("Not Successful")
                Success = 0;
            else
                HSS = out(3)*25.6; %High Set Speed
                LSS = out(4)*.1; %Low Set Speed
                SS = HSS+LSS; %Set Speed
                
                HSR = out(5)*25.6; %High Real Speed
                LSR = out(6)*.1; %Low Real Speed
                SR = HSR+LSR; %Real Speed
                
                HTS = out(7)*25.6; %High Set Temperature
                LTS = out(8)*.1; %Low Set Temperature
                TS = HTS+LTS; %Set Temperature
                
                HTR = out(9)*25.6; %High Real Temperature
                LTR = out(10)*.1; %Low Real Temperature
                TR = HTR+LTR; %Real Temperature
                Success = 1;
                display("Temperature Obtained! Temp = "+TR+"C")
            end
        end
        
        function TempHP = getTC(app,MUnb)
            dataIn = [1 1]; %write 2 pieces of info in order to receive 2 pieces of info
            dataOut = writeRead(app.HW(MUnb).Therm,dataIn,'uint16'); %uint16 is the largest integer you can use with this function therefore you need 2
            bytepack=uint32(dataOut(1)); %since there are two separate pieces you need to combine them to a 32-bit integer
            bytepack=bitshift(bytepack,16); %data(1) is the 1st half and is converted to uint32 and the data is shifted to the correct side.
            z = bitor(bytepack,uint32(dataOut(2))); %data(2) is the 2nd half and bitor() is used to place this into the correct side of uint32
            z = dec2bin(z,32); %create a 32-bit binary number
            TempHP = bin2dec(z(2:14))*0.25;  %from the MAX31855 datasheet, bits 18-31 is the temperature in Celsius. These number are referenced backwards in matlab.
        end
        
        function [TempTC temp_t] = WaitPlotTemp(app,TempThresh,Err,WaitTime_s) %Wait Function to Measure/Plot Temperature
            TempTC = getTC(app); %Get current thermocouple temperature
            temp_t = toc; %Get current process time
            
            while(getTC(app)> TempThresh+Err || getTC(app)<TempThresh-Err) %While temperture is out of desired range, wait & plot
                TempTC =  [getTC(app) TempTC]; % Record Temperature (C)
                temp_t = [toc temp_t]; % Record time (s)
                plot(app.TempTime_1,temp_t/3600,TempTC,'-o','LineWidth',2,'Color',[1,.4,0]) %Plot thermocouple temperature vs. time
                pause(WaitTime_s); % Wait specified time
            end
        end
        
        %Convert Function from Desired Temperature Thermocouple to Hotplate Set Temperature Based on Calibration Fit
        function Tnew = CorrectT(app,T,MUnb)
            Tnew = CorrectT_ext(app,T,MUnb);
        end
        
        % RunInterCV perform a CV measurement on each pin that has been set to measure by the user.
        % It returns a flag "meas_flag" equals to 1 after a measurement has been completed.
        function [th Ih] = RunIterCV(app,V,CVprogram,PreBias_1,PreBiasTime_1,th,Ih,PinState,ArdP,LampSet,LampColor,CVPlots,PlotCVby2,MUnb) % Run Iterative CV Measurement
            [th Ih] = RunIterCV_ext(app,V,CVprogram,PreBias_1,PreBiasTime_1,th,Ih,PinState,ArdP,LampSet,LampColor,CVPlots,PlotCVby2,MUnb);
        end
        
        % Function to call at regular time intervals in the infinite loop to check the status of each measurement unit
        function MD = fcncallback(app,MUnb,MD) % MUnb is the number of the measurement unit considered (1,2 or 3)
            MD=fcncallback_ext(app,MUnb,MD);
        end
        
        % Function for flatband fitting
        function FlatbandFitting(app,PinState,PlotCVby2)
            FlatbandFitting_ext(app,PinState,PlotCVby2)
        end
        
        % Derivative function for flatband
        function VFBfitNDeriv(app,p,m,IterM_1,PlotCVby2_p)
            VFBfitNDeriv_ext(app,p,m,IterM_1,PlotCVby2_p)
        end
        
    end % End of the public methods?
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             instrreset %Reset all intruments
            %             app.HW = InitHardware(app); %initialize arduino and hotplate connections
        end

        % Button pushed function: Export_CV_1
        function Export_CV_1Pushed(app, event)
            clf; shg;
            try
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                CVPlots = [app.CV1_1,app.CV2_1,app.CV3_1,app.CV4_1];
                ValidCVPlots = CVPlots(PinState==1);
                for e1 = 1:length(CVPlots)
                    if (PinState(e1))
                        subplot(2,2,e1)
                        hold on
                        set(gca, 'ColorOrder', jet(app.Iter_tot_1.Value+1),'FontSize',14)
                        colormap('jet');
                        IterM_1 = app.IterM_1.Value;
                        map = fliplr(hot(length(CVPlots(e1).Children)/IterM_1+2));
                        t_off = tInSec(app, string(app.t_offset_unit_1.Value), app.TimeOffset_1.Value);
                        TimeArray = t_off+(0:app.Iter_tot_1.Value).*app.dt_1.Value
                        if (app.dt_1.Value == 0)
                            lgn = "Iter: " + (0:app.Iter_tot_1.Value);
                        else
                            lgn = (TimeArray) + " " + string(app.t_inc_unit_1.Value);
                        end
                        h= [];
                        for e2 =  (length(CVPlots(e1).Children)/IterM_1):-1:1
                            x = [];
                            y = [];
                            ind = e2*IterM_1;
                            for e3 = 0:1:IterM_1-1
                                x = [(CVPlots(e1).Children(ind-e3).XData)' x]
                                y = [(CVPlots(e1).Children(ind-e3).YData)' y]
                            end
                            htemp = plot(x,y,'LineWidth', 2,'Color',map(end-e2,:))
                            h = [h, htemp(1)];
                        end
                        legend(h,lgn)
                        title("Pin "+e1);
                        xlabel(CVPlots(e1).XLabel.String);
                        ylabel(CVPlots(e1).YLabel.String);
                        hold off
                    end
                end
            end
        end

        % Button pushed function: Export_byC2_1
        function Export_byC2_1Pushed(app, event)
            clf; shg;
            try
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                byC2Plots = [app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1];
                ValidbyC2Plots = byC2Plots(PinState==1);
                
                for e1 = 1:length(byC2Plots)
                    if (PinState(e1))
                        subplot(2,2,e1)
                        hold on
                        set(gca, 'ColorOrder', fliplr(hot(ceil(length(app.byC2_1_1.Children)./2))))
                        IterM_1 = app.IterM_1.Value;
                        
                        for e2 = ((length(byC2Plots(e1).Children)-1)/IterM_1)-1:-2:1
                            plot(byC2Plots(e1).Children(e2).XData,byC2Plots(e1).Children(e2).YData,'-','LineWidth',2)
                        end
                        for e2 = ((length(byC2Plots(e1).Children)-1)/IterM_1):-2:1
                            plot(byC2Plots(e1).Children(e2).XData,byC2Plots(e1).Children(e2).YData,'k*','LineWidth',2)
                        end
                        
                        title("Pin "+e1);
                        xlabel(byC2Plots(e1).XLabel.String);
                        ylabel(byC2Plots(e1).YLabel.String);
                        hold off
                    end
                end
            end
        end

        % Button pushed function: ExportVFBtime_1
        function ExportVFBtime_1Pushed(app, event)
            clf; shg;
            try
                VfbTimePlotColors = ["bo-","yo-","go-","mo-"];
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                StdOff = 0;
                for e = 1:length(PinState)
                    if(~PinState(e))
                        StdOff = StdOff + 1;
                    else
                        break;
                    end
                end
                ValidColors = fliplr(VfbTimePlotColors(PinState==1))
                for e = length(app.VFBtime_1.Children):-1:1
                    errorbar(app.VFBtime_1.Children(e).XData,app.VFBtime_1.Children(e).YData,app.P(e+StdOff).VfbStd,ValidColors(e),'LineWidth', 2)
                    title(app.VFBtime_1.Title.String);
                    xlabel(app.VFBtime_1.XLabel.String);
                    ylabel(app.VFBtime_1.YLabel.String);
                    hold on
                end
                hold off
            end
        end

        % Button pushed function: OpenFileLocationButton
        function OpenFileLocationButtonPushed(app, event)
            app.dir = uigetdir('C:\');
            try
                app.FileLoc.Value = app.dir;
                cd(app.dir);
            end
        end

        % Button pushed function: StartProcessButton
        function StartProc(app, event)
            if app.idleFlag == 1 && app.devicesConnected == 1
                %Turns off holds for/reset all plots in app
                % Unit 1
                hold(app.It_1,'off'); hold(app.VFBtime_1,'off'); hold(app.TempTime_1,'off')
                hold(app.CV1_1,'off'); hold(app.CV2_1,'off'); hold(app.CV3_1,'off'); hold(app.CV4_1,'off');
                hold(app.CV5_1,'off'); hold(app.CV6_1,'off'); hold(app.CV7_1,'off'); hold(app.CV8_1,'off');
                hold(app.byC2_1_1,'off'); hold(app.byC2_2_1,'off'); hold(app.byC2_3_1,'off'); hold(app.byC2_4_1,'off');
                hold(app.byC2_5_1,'off'); hold(app.byC2_6_1,'off'); hold(app.byC2_7_1,'off'); hold(app.byC2_8_1,'off');
                
                % Unit 2
                hold(app.It_2,'off'); hold(app.VFBtime_2,'off'); hold(app.TempTime_2,'off')
                hold(app.CV1_2,'off'); hold(app.CV2_2,'off'); hold(app.CV3_2,'off'); hold(app.CV4_2,'off');
                hold(app.CV5_2,'off'); hold(app.CV6_2,'off'); hold(app.CV7_2,'off'); hold(app.CV8_2,'off');
                hold(app.byC2_1_2,'off'); hold(app.byC2_2_2,'off'); hold(app.byC2_3_2,'off'); hold(app.byC2_4_2,'off');
                hold(app.byC2_5_2,'off'); hold(app.byC2_6_2,'off'); hold(app.byC2_7_2,'off'); hold(app.byC2_8_2,'off');
                
                % Unit 3
                hold(app.It_3,'off'); hold(app.VFBtime_3,'off'); hold(app.TempTime_3,'off')
                hold(app.CV1_3,'off'); hold(app.CV2_3,'off'); hold(app.CV3_3,'off'); hold(app.CV4_3,'off');
                hold(app.CV5_3,'off'); hold(app.CV6_3,'off'); hold(app.CV7_3,'off'); hold(app.CV8_3,'off');
                hold(app.byC2_1_3,'off'); hold(app.byC2_2_3,'off'); hold(app.byC2_3_3,'off'); hold(app.byC2_4_3,'off');
                hold(app.byC2_5_3,'off'); hold(app.byC2_6_3,'off'); hold(app.byC2_7_3,'off'); hold(app.byC2_8_3,'off');
                
                %Disable control of turning on/off pins
                % Unit 1
                app.P1_1.Enable = 'off'; app.P2_1.Enable = 'off'; app.P3_1.Enable = 'off'; app.P4_1.Enable = 'off';
                app.P5_1.Enable = 'off'; app.P6_1.Enable = 'off'; app.P7_1.Enable = 'off'; app.P8_1.Enable = 'off';
                % Unit 2
                app.P1_2.Enable = 'off'; app.P2_2.Enable = 'off'; app.P3_2.Enable = 'off'; app.P4_2.Enable = 'off';
                app.P5_2.Enable = 'off'; app.P6_2.Enable = 'off'; app.P7_2.Enable = 'off'; app.P8_2.Enable = 'off';
                % Unit 3
                pp.P1_3.Enable = 'off'; app.P2_3.Enable = 'off'; app.P3_3.Enable = 'off'; app.P4_3.Enable = 'off';
                app.P5_3.Enable = 'off'; app.P6_3.Enable = 'off'; app.P7_3.Enable = 'off'; app.P8_3.Enable = 'off';
                
                % Disable control of turning on/off all pins
                app.SwitchAll_HP1.Enable = 'off';
                app.SwitchAll_HP2.Enable = 'off';
                app.SwitchAll_HP3.Enable = 'off';
                
                %Set pin lamps to gray
                % Unit 1
                app.P1Lamp_1.Color = [.9, .9, .9]; app.P2Lamp_1.Color = [.9, .9, .9]; app.P3Lamp_1.Color = [.9, .9, .9]; app.P4Lamp_1.Color = [.9, .9, .9];
                app.P5Lamp_1.Color = [.9, .9, .9]; app.P6Lamp_1.Color = [.9, .9, .9]; app.P7Lamp_1.Color = [.9, .9, .9]; app.P8Lamp_1.Color = [.9, .9, .9];
                % Unit 2
                app.P1Lamp_2.Color = [.9, .9, .9]; app.P2Lamp_2.Color = [.9, .9, .9]; app.P3Lamp_2.Color = [.9, .9, .9]; app.P4Lamp_2.Color = [.9, .9, .9];
                app.P5Lamp_2.Color = [.9, .9, .9]; app.P6Lamp_2.Color = [.9, .9, .9]; app.P7Lamp_2.Color = [.9, .9, .9]; app.P8Lamp_2.Color = [.9, .9, .9];
                % Unit 3
                app.P1Lamp_3.Color = [.9, .9, .9]; app.P2Lamp_3.Color = [.9, .9, .9]; app.P3Lamp_3.Color = [.9, .9, .9]; app.P4Lamp_3.Color = [.9, .9, .9];
                app.P5Lamp_3.Color = [.9, .9, .9]; app.P6Lamp_3.Color = [.9, .9, .9]; app.P7Lamp_3.Color = [.9, .9, .9]; app.P8Lamp_3.Color = [.9, .9, .9];
                
                
                %% CV Program Definition for Impedance Anlayzer
                if (app.Sweep_gnl.Value == "Down")
                    SWD = "SWD2"; %Set program parameter to sweep down
                else
                    SWD = "SWD1"; %Set program parameter to sweep up
                end
                
                % Depending on integration time specified in app input, set
                % corresponding parameter in the program script
                if (app.ITM_gnl.Value == "500 us")
                    ITMgnl = "ITM1";
                elseif (app.ITM_gnl.Value == "5 ms")
                    ITMgnl = "ITM2";
                elseif (app.ITM_gnl.Value == "100 ms")
                    ITMgnl = "ITM3";
                end
                
                %Set frequency multiplaction factor based on defined unit in app dropdown
                if (app.Freq_unit_gnl.Value == "KHz")
                    FreqU = 1e3;
                elseif (app.Freq_unit_gnl.Value == "MHz")
                    FreqU = 1e6;
                end
                
                %Define CV program to be read in by Impedance Analyzer
                Prog_CV = "PROG"...
                    + "'10 FNC1'," ... %Impedance Measurement
                    + "'20 SWM2'," ... % Single Mode Sweep
                    + "'30 IMP5'," ... % Cs-Rs Circuit
                    + "'40 SWP2'," ... % DC Bias Sweep
                    + "'50 "+SWD+"'," ... % Sweep Direction
                    + "'60 "+ITMgnl+"'," ... % Integration Time
                    + "'70 NOA_1="+app.NOA_gnl.Value+"'," ... % # of Averages
                    + "'80 OSC_1="+app.OSC_gnl.Value*1e-3+";FREQ="+app.Frequency_gnl.Value*FreqU+"'," ... % AC Amplitude (V) & Frequency
                    + "'90 START="+app.VNeg_gnl.Value+";STOP="+app.VPos_gnl.Value+"'," ... %Starting Negative & Positive Volgates
                    + "'100 STEP="+app.VStep_gnl.Value*1e-3+"'," ... %DC Sweep Step Magnitude (V)
                    + "'110 DTIME=0'," ... % Measurement Delay Time Set to 0 (No Delay)
                    + "'120 SHT1'," ... % Short Calibration Set to On
                    + "'130 OPN1'," ... % Open Calibration Set to On
                    + "'140 AUTO'," ... % Auto-Scale A & B
                    + "'150 CPYM2'," ... % Copy Data Mode 2
                    + "'160 SWTRG'," ... % Single Trigger Run
                    + "'170 COPY'," ... % Copy Data to Instrument
                    + "'180 DCOFF'," ... % Doesn't work, but attempt to turn off DC bias
                    + "'190 END'";
                
                LampSet = [app.P1Lamp_1.Color, app.P2Lamp_1.Color, app.P3Lamp_1.Color, app.P4Lamp_1.Color]; %Define an array of lamp color objects
                LampColor = ["blue","yellow","green","white"]; %Define an array of lamp colors
                % Hotplate 1
                MD(1).PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value, app.P5_1.Value, app.P6_1.Value, app.P7_1.Value, app.P8_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
                MD(1).ArdP = [app.AP1_1.Value, app.AP2_1.Value, app.AP3_1.Value, app.AP4_1.Value, app.AP5_1.Value, app.AP6_1.Value, app.AP7_1.Value, app.AP8_1.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
                % Hotplate 2
                MD(2).PinState = [app.P1_2.Value, app.P2_2.Value, app.P3_2.Value, app.P4_2.Value, app.P5_2.Value, app.P6_2.Value, app.P7_2.Value, app.P8_2.Value]; %Define array of pin value objects (objects that indicate on or off state)
                MD(2).ArdP = [app.AP1_2.Value, app.AP2_2.Value, app.AP3_2.Value, app.AP4_2.Value, app.AP5_2.Value, app.AP6_2.Value, app.AP7_2.Value, app.AP8_2.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
                % Hotplate 3
                MD(3).PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value, app.P5_1.Value, app.P6_1.Value, app.P7_1.Value, app.P8_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
                MD(3).ArdP = [app.AP1_1.Value, app.AP2_1.Value, app.AP3_1.Value, app.AP4_1.Value, app.AP5_1.Value, app.AP6_1.Value, app.AP7_1.Value, app.AP8_1.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
                % Save figure handles to CV plots for each hotplate (in a structure array of objects). Access CV plot 3 in MU 1 with: MD(1).Plots.CVhandle(3).
                MD(1).Plots.CV = [app.CV1_1,app.CV2_1,app.CV3_1,app.CV4_1,app.CV5_1,app.CV6_1,app.CV7_1,app.CV8_1];
                MD(2).Plots.CV = [app.CV1_2,app.CV2_2,app.CV3_2,app.CV4_2,app.CV5_2,app.CV6_2,app.CV7_2,app.CV8_2];
                MD(3).Plots.CV = [app.CV1_3,app.CV2_3,app.CV3_3,app.CV4_3,app.CV5_3,app.CV6_3,app.CV7_3,app.CV8_3];
                % Save figure handles to derivative plots for each hotplate
                MD(1).Plots.CVby2=[app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1,app.byC2_5_1,app.byC2_6_1,app.byC2_7_1,app.byC2_8_1]; %Define array of plot objects for CV & 1/C^2 vs. V curves
                MD(2).Plots.CVby2=[app.byC2_1_2,app.byC2_2_2,app.byC2_3_2,app.byC2_4_2,app.byC2_5_2,app.byC2_6_2,app.byC2_7_2,app.byC2_8_2];
                MD(3).Plots.CVby2=[app.byC2_1_3,app.byC2_2_3,app.byC2_3_3,app.byC2_4_3,app.byC2_5_3,app.byC2_6_3,app.byC2_7_3,app.byC2_8_3];
                % Preallocate the data structure arrays based on the max number of iterations maxnbIt and the number of points from the analyzer L (check whether constant)
                %MD(MUnb).ExpData.Pin(p).C = zeros(L,maxnbIt);
                %MD(MUnb).ExpData.Pin(p).R = zeros(L,maxnbIt);
                %MD(MUnb).ExpData.Pin(p).V = zeros(L,maxnbIt);
                
                % Number of iterations
                MD(1).ExpData.Setup.IterM=app.IterM_1.Value;
                MD(2).ExpData.Setup.IterM=app.IterM_2.Value;
                MD(3).ExpData.Setup.IterM=app.IterM_3.Value;
                
                for mu=1:3
                    for i=1:length(MD(mu).PinState) %For all pins, set parameters to null
                        set(MD(mu).Plots.CV(i), 'ColorOrder', jet((app.Iter_tot_gnl.Value+1)*MD(mu).ExpData.Setup.IterM.Value)); %Define color order for plotting based on % of iterations
                        MD(mu).ExpData.Pin(i).C  = []; % Capacitance read from the impedance analyzer
                        MD(mu).ExpData.Pin(i).R=[]; % Resistance read from the impedance analyzer
                        MD(mu).ExpData.Pin(i).V = []; % Voltage read from the impedance analyzer
                        % app.P(i).Vall = []; % Parameter only used with the function VFBfitn, which is not used
                        MD(mu).ExpData.Pin(i).Vfit = [];
                        MD(mu).ExpData.Pin(i).Cfit = [];
                        MD(mu).ExpData.Pin(i).Vfb = [];
                        MD(mu).ExpData.Pin(i).tfb = [];%Null V,Vfit,Cfit,Vfb,tfb (flatband voltage/time)
                        MD(mu).ExpData.Pin(i).VfbAve = [];
                        MD(mu).ExpData.Pin(i).VfbStd = []; %Null average & standard deviation Vfb
                        MD(mu).ExpData.log.T = [];
                        MD(mu).ExpData.log.Ttime =[];
                        MD(mu).ExpData.log.I = [];
                        MD(mu).ExpData.log.Itime = [];
                    end
                end
                
                %% Connect Keithley and impedance analyzer
                % Currently done manually by the user with the power button in the general panel
                
                % Graph handles
                drawnow;
                % Temp vs time plot handles
                MD(1).Plots.Temp=app.TempTime_1;
                MD(2).Plots.Temp=app.TempTime_2;
                MD(3).Plots.Temp=app.TempTime_3;
                %% Current vs time plot handles
                MD(1).Plots.Current=app.It_1;
                MD(2).Plots.Current=app.It_2;
                MD(3).Plots.Current=app.It_3;
                %% Vfb vs time plot handles
                MD(1).Plots.VfbTime=app.VFBvsTimeTab_1;
                MD(2).Plots.VfbTime=app.VFBvsTimeTab_1;
                MD(3).Plots.VfbTime=app.VFBvsTimeTab_1;
                
                %% Unit parameters
                % Prebias time
                MD(1).ExpData.Setup.PreBiasTime=app.PreBiasTime_1.Value;
                MD(2).ExpData.Setup.PreBiasTime=app.PreBiasTime_2.Value;
                MD(3).ExpData.Setup.PreBiasTime=app.PreBiasTime_3.Value;
                % Offset
                MD(1).ExpData.Setup.t_offset_unit =app.t_offset_unit_1.Value;
                MD(2).ExpData.Setup.t_offset_unit =app.t_offset_unit_2.Value;
                MD(3).ExpData.Setup.t_offset_unit =app.t_offset_unit_3.Value;
                MD(1).ExpData.Setup.t_offset_value =app.TimeOffset_1.Value;
                MD(2).ExpData.Setup.t_offset_value =app.TimeOffset_2.Value;
                MD(3).ExpData.Setup.t_offset_value =app.TimeOffset_3.Value;
                % Temperatures
                MD(1).ExpData.Setup.TempC=app.TempC_1.Value;
                MD(2).ExpData.Setup.TempC=app.TempC_2.Value;
                MD(3).ExpData.Setup.TempC=app.TempC_3.Value;
                MD(1).ExpData.Setup.TempH=app.TempH_1.Value;
                MD(2).ExpData.Setup.TempH=app.TempH_2.Value;
                MD(3).ExpData.Setup.TempH=app.TempH_3.Value;
                
                
                %% Save Parameters to Text File:
                fileDest = app.FileLoc.Value;
                
                fileID = fopen(fileDest+"\ExpParams.txt",'wt');
                
                % General parameters
                fprintf(fileID,"Parameter File:\n\nCommon Parameters: \n");
                fprintf(fileID,"Sweep Parameters: \n");
                fprintf(fileID,"Frequency: "+app.Frequency_gnl.Value+" "+app.Freq_unit_gnl.Value+"      VAC: "+app.OSC_gnl.Value+" mV"+"\n");
                fprintf(fileID,"Negative Voltage: "+app.VNeg_gnl.Value+" V       Positive Voltage: "+app.VPos_gnl.Value+" V \n");
                fprintf(fileID,"Voltage Step: "+app.VStep_gnl.Value+" mV         Averages per Point: "+app.NOA_gnl.Value+"\n");
                fprintf(fileID,"Integration Time: "+app.ITM_gnl.Value+"         Sweep Direction: "+app.Sweep_gnl.Value+"\n");
                
                fprintf(fileID,"\nExperiment Parameters: \n");
                fprintf(fileID,"Cycle time: "+app.dt_gnl.Value+" "+app.t_inc_unit_gnl.Value);
                fprintf(fileID,"Bias Voltage (both cycle bias and pre-bias): "+app.Bias_gnl.Value+" V \n");
                fprintf(fileID,"Total number of cycles: "+app.Iter_tot_gnl.Value);
                
                % Unit 1
                fprintf(fileID,"Measurement name: "+app.DataFileName_MU1.Value);
                fprintf(fileID,"Nitride Thickness: "+app.THK_1.Value+" nm\n");
                fprintf(fileID,"Heating temperature: "+app.TempH_1.Value+" °C");
                fprintf(fileID,"Cooling temperature: "+app.TempH_1.Value+" °C");
                fprintf(fileID,"Time Offset: "+app.TimeOffset_1.Value+" "+app.t_offset_unit_1.Value+"\n");
                fprintf(fileID,"Pre-CV bias Time: "+app.PreBiasTime_1.Value + " min \n");
                fprintf(fileID,"Number of iterations/measurement: "+app.IterM_1.Value+"\n");
                
                % Unit 2
                fprintf(fileID,"Measurement name: "+app.DataFileName_MU2.Value);
                fprintf(fileID,"Nitride Thickness: "+app.THK_2.Value+" nm\n");
                fprintf(fileID,"Heating temperature: "+app.TempH_2.Value+" °C");
                fprintf(fileID,"Cooling temperature: "+app.TempH_2.Value+" °C");
                fprintf(fileID,"Time Offset: "+app.TimeOffset_2.Value+" "+app.t_offset_unit_2.Value+"\n");
                fprintf(fileID,"Pre-CV bias Time: "+app.PreBiasTime_2.Value + " min \n");
                fprintf(fileID,"Number of iterations/measurement: "+app.IterM_2.Value+"\n");
                
                % Unit 3
                fprintf(fileID,"Measurement name: "+app.DataFileName_MU3.Value);
                fprintf(fileID,"Nitride Thickness: "+app.THK_3.Value+" nm\n");
                fprintf(fileID,"Heating temperature: "+app.TempH_3.Value+" °C");
                fprintf(fileID,"Cooling temperature: "+app.TempH_3.Value+" °C");
                fprintf(fileID,"Time Offset: "+app.TimeOffset_3.Value+" "+app.t_offset_unit_3.Value+"\n");
                fprintf(fileID,"Pre-CV bias Time: "+app.PreBiasTime_3.Value + " min \n");
                fprintf(fileID,"Number of iterations/measurement: "+app.IterM_3.Value+"\n");
                
                fclose(fileID);
                
                %% Run & Get Data:
                
                % Allowed error in temperature
                MD(1).MDdata.Err = 1; %Set Temperature +/- Error in Degree (C) (e.x. app will execute function to within +/- 1 degree of set temp)
                
                % Initlialize parameters for each unit
                for mu=1:3
                    % Apply temperature calibration to find the temperature to set on each hotplate
                    MD(mu).ExpData.Setup.CalTempH = CorrectT(app,MD(mu).ExpData.Setup.TempH,mu); %Set Hotplate Set Stress Temp Based on Hotplate Thermocouple Calibration (C)
                    MD(mu).ExpData.Setup.CalTempC = CorrectT(app,MD(mu).ExpData.Setup.TempC,mu); %Set Hotplate Set Cool Temp Based on Hotplate Thermocouple Calibration (C)
                    
                    setHotPlateTemperature(app,mu,MD(mu).ExpData.Setup.CalTempC); % Execute Function to Set Hotplate Temperaure
                    
                    if(getTC(app,mu) > MD(mu).ExpData.Setup.TempC) %If Thermocouple temperature is greater than the cool/measurement temperature
                        writeDigitalPin(app.Arduino,'D11',1) %Turn on Fan (verify pin number)
                        MD(MUnb).MDdata_fanflag=1; % Set fan flag
                    else
                        writeDigitalPin(app.Arduino,'D11',0) %Turn off Fan (verify pin number)
                        MD(MUnb).MDdata_fanflag=0; % Set fan flag
                    end
                    
                    % General parameters values
                    MD(mu).ExpData.Setup.stressBiasValue=app.Bias_gnl; % Bias value
                    MD(mu).ExpData.Setup.biastime_sec = tInSec(app, string(app.t_inc_unit_gnl.Value), app.dt_gnl.Value); % Bis time in seconds
                    % store voltage in MD structure for each pin p (because this is the way FVBfitNDeriv_ext uses the voltage)
                    for p=1:8
                        % This is not necessarily needed. Only used in RunIterCV to fill the voltage array in case it is not taken directly as output from the impedance analyzer.
                        % See if the impedance analyzer outputs voltage correctly.
                        % The voltage input sent to the analyzer is found directly from the values entered in the app panel by the user
                        MD(mu).ExpData.Pin(p).Vinput = app.VNeg_gnl.Value:app.VStep_gnl.Value*1e-3:app.VPos_gnl.Value; % voltage sweep array for the CV measurement
                    end
                    
                    % Initialize unit log parameters
                    MD(mu).ExpData.log.Ttime = 0; % set log.Ttime, the log function will log the initialization point as the first value is 0 (needs to be a number as logvalues_ext.m will crash if empty)
                    MD(mu).ExpData.log.Itime = 0;
                    MD(MUnb).MDdata.CVStartTime=[];
                    % Get initial temperature value
                    InitTemp = getTC(app,mu);
                    MD(mu).ExpData.log.T = InitTemp;
                    % Get initial current value
                    MD(mu).ExpData.log.I=str2double(strsplit(query(app.HW(1).KEITH, ":READ?"),','))'; % measurement although all pins are disconnected from Keithley becasue an initial value is needed to have the same number of elements as in the time log array
                    
                    %% Initialize flags
                    MD(mu).MDdata.meas_flag=0; % Set meas_flag to 0 for all units to indicate that a measurement can start
                    MD(MUnb).MDdata.stress_completed_flag=1; % Set stress completed flag to 1 (=true) to allow initial CV measurement
                    MD(MUnb).MDdata.startbiastime=0; % condition to allow the initial meas to occur in fcncallback_ext (since the time will be smaller than the set bias time)
                    MD(MUnb).MDdata.finish_flag=0; % Where is the finish flag updated? Should it be in RunIter CV after each measurement, if equals the max number of measurements then set to 1
                    % Hold all graphs
                    hold(MD(mu).Plots.Temp,'on')
                    hold(MD(mu).Plots.Currrent,'on')
                end
                app.stopFlag=0; % Flag to stop while loop
                
                
                tic; %Start recording time during measurement (logvalues_ext)
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% RUN PROCESS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                CV_timeLoop(app,MD,CVProgram);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %After completed meaurement
                save(fileDest+"\"+app.DataFileName.Value+".mat"); %Save all workspace data
            else % else for if app.idleFlag == 1
                warndlg('Another process is currently running','System error');
            end % end for if app.idleFlag == 1
        end

        % Button pushed function: StopProcessButton
        function StopProc(app, event)
            CV_stopProcedure(app);
        end

        % Button pushed function: UpdateFit_1
        function UpdateFit_1ButtonPushed(app, event)
            fitfail = 0;
            PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
            PlotCVby2 = [app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1]; %Define array of plot objects for 1/C^2 vs. V curves
            VfbTimePlotColors = ["bo-","yo-","go-","mo-"];
            
            for p = 1:length(PinState)
                if(PinState(p))
                    [Cfit,Vfit,Vfbtemp,fitfail] = VFBfitN(app,app.P(p).C,app.P(p).V,length(PlotCVby2.Children),PlotCVby2(p));
                    app.P(p).Vfb = [];
                    app.P(p).VfbStd = [];
                    iterM = app.IterM_1.Value;
                    
                    for i = (length(Vfbtemp)/iterM):-1:1
                        ind = i*iterM;
                        app.P(p).Vfb = [app.P(p).Vfb mean(Vfbtemp(ind:-1:ind-iterM+1)')]
                        app.P(p).VfbStd = [app.P(p).VfbStd std(Vfbtemp(ind:-1:ind-iterM+1)')]
                    end
                    
                    if(fitfail)
                        msgbox("Fit Failed :(");
                        return;
                    end
                    
                    if (app.dt_1.Value == 0)
                        tfb = (0:(length(app.Vfb)-1))
                        plot(app.VFBtime_1,0:(length(app.Vfb)-1),app.Vfb,VfbTimePlotColors(p),'LineWidth',2)
                        app.VFBtime_1.XLabel.String = "# of Sweeps";
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Number of Sweeps";
                    else
                        m = length(app.CV1_1.Children)/app.IterM_1.Value
                        tfb = 0:app.dtime:app.dtime*(length(app.Vfb)-1)
                        plot(app.VFBtime_1,tfb/3600,app.Vfb,VfbTimePlotColors(p),'LineWidth',2)
                        app.VFBtime_1.XLabel.String = "Time (hr)";
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Time";
                    end
                end
            end
            
            fileDest = app.FileLoc.Value
            
            TC = app.TC; TC_time = app.TC_time;
            app.I = Ih; app.It_t = th;
            Data = app.P;
            save(fileDest+"\"+app.DataFileName.Value+".mat","Data","TC","TC_time","Ih","th");
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                CV_RebootSystem(app);
            end
            delete(app);
        end

        % Button pushed function: Export_It_1
        function Export_It_1Button(app, event)
            clf; shg;
            try
                hold on
                for i =  length(app.It_1.Children):-1:1
                    plot(app.It_1.Children(i).XData,abs(app.It_1.Children(i).YData),'ko-','LineWidth',2)
                end
                hold off
                set(gca,'FontSize',14,'YScale','Log');
                title(app.It_1.Title.String);
                xlabel(app.It_1.XLabel.String);
                ylabel("|Current (A)|");
                hold off
            end
        end

        % Button pushed function: Export_TempTime_1
        function Export_TempTime_1Button(app, event)
            clf; shg;
            try
                hold on
                for i =  length(app.TempTime_1.Children):-1:1
                    plot(app.TempTime_1.Children(i).XData,abs(app.TempTime_1.Children(i).YData),'-o','LineWidth',2,'Color',[1,.4,0])
                end
                hold off
                set(gca,'FontSize',14);
                title(app.TempTime_1.Title.String);
                xlabel(app.TempTime_1.XLabel.String);
                ylabel(app.TempTime_1.YLabel.String);
                hold off
            end
        end

        % Value changed function: DerPeaks_1_1
        function DerPeaks_1_1Pressed(app, event)
            value = app.DerPeaks_1_1.Value;
            if (value)
                app.byC2_1_1.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_1_1.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_1_1.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_1_1.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: P1_1
        function P1_1Changed(app, event)
            value = app.P1_1.Value;
            if(value)
                app.P1_1.Text = "ON";
                app.P1Lamp_1.Color = "red";
            else
                app.P1_1.Text = "OFF";
                app.P1Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P2_1
        function P2_1Changed(app, event)
            value = app.P2_1.Value;
            if(value)
                app.P2_1.Text = "ON";
                app.P2Lamp_1.Color = [1,.41,.16];
            else
                app.P2_1.Text = "OFF";
                app.P2Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P3_1
        function P3_1Changed(app, event)
            value = app.P3_1.Value;
            if(value)
                app.P3_1.Text = "ON";
                app.P3Lamp_1.Color = [.93,.69,.13];
            else
                app.P3_1.Text = "OFF";
                app.P3Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P4_1
        function P4_1Changed(app, event)
            value = app.P4_1.Value;
            if(value)
                app.P4_1.Text = "ON";
                app.P4Lamp_1.Color = "yellow";
            else
                app.P4_1.Text = "OFF";
                app.P4Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Button pushed function: PinReset_1
        function PinReset_1ButtonPushed(app, event)
            app.P1_1.Enable = 'on'; app.P2_1.Enable = 'on'; app.P3_1.Enable = 'on'; app.P4_1.Enable = 'on';
            app.P5_1.Enable = 'on'; app.P6_1.Enable = 'on'; app.P7_1.Enable = 'on'; app.P8_1.Enable = 'on';
        end

        % Value changed function: P5_1
        function P5_1Changed(app, event)
            value = app.P5_1.Value;
            if(value)
                app.P5_1.Text = "ON";
                app.P5Lamp_1.Color = [0,1,1];
            else
                app.P5_1.Text = "OFF";
                app.P5Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P6_1
        function P6_1Changed(app, event)
            value = app.P6_1.Value;
            if(value)
                app.P6_1.Text = "ON";
                app.P6Lamp_1.Color = "blue";
            else
                app.P6_1.Text = "OFF";
                app.P6Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P7_1
        function P7_1Changed(app, event)
            value = app.P7_1.Value;
            if(value)
                app.P7_1.Text = "ON";
                app.P7Lamp_1.Color = "magenta";
            else
                app.P7_1.Text = "OFF";
                app.P7Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P8_1
        function P8_1Changed(app, event)
            value = app.P8_1.Value;
            if(value)
                app.P8_1.Text = "ON";
                app.P8Lamp_1.Color = [0.49,0.18,0.56];
            else
                app.P8_1.Text = "OFF";
                app.P8Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: SwitchAll_HP3
        function ToggleAllPinsHP3(app, event)
            value = app.SwitchAll_HP3.Value;
            CV_ToggleAllPinsHP3(app,value);
        end

        % Value changed function: PowerSwitch
        function TogglePower(app, event)
            value = app.PowerSwitch.Value;
            if app.idleFlag == 1
                if strcmp(value,'On') == 1
                    app.HW = CV_ConnectDevices(app);
                else
                    CV_DisconnectDevices(app);
                end
            end
        end

        % Value changed function: SwitchAll_HP1
        function ToggleAllPinsHP1(app, event)
            value = app.SwitchAll_HP1.Value;
            CV_ToggleAllPinsHP1(app,value);
        end

        % Value changed function: SwitchAll_HP2
        function ToggleAllPinsHP2(app, event)
            value = app.SwitchAll_HP2.Value;
            CV_ToggleAllPinsHP2(app,value);
        end

        % Value changed function: DerPeaks_1_2
        function DerPeaks_1_2Pressed(app, event)
            value = app.DerPeaks_1_2.Value;
            if (value)
                app.byC2_5_1.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_5_1.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_5_1.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_5_1.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: DerPeaks_2_1
        function DerPeaks_2_1Pressed(app, event)
            value = app.DerPeaks_2_1.Value;
            if (value)
                app.byC2_1_2.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_1_2.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_1_2.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_1_2.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: DerPeaks_2_2
        function DerPeaks_2_2Pressed(app, event)
            value = app.DerPeaks_2_2.Value;
            if (value)
                app.byC2_5_2.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_5_2.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_5_2.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_5_2.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: DerPeaks_3_1
        function DerPeaks_3_1Pressed(app, event)
            value = app.DerPeaks_3_1.Value;
            if (value)
                app.byC2_1_3.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_1_3.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_1_3.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_1_3.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: DerPeaks_3_2
        function DerPeaks_3_2Pressed(app, event)
            value = app.DerPeaks_3_2.Value;
            if (value)
                app.byC2_5_3.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_5_3.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_5_3.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_5_3.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: P1_2
        function P1_2Changed(app, event)
            value = app.P1_2.Value;
        end

        % Value changed function: P2_2
        function P2_2Changed(app, event)
            value = app.P2_2.Value;
        end

        % Value changed function: P3_2
        function P3_2Changed(app, event)
            value = app.P3_2.Value;
            
        end

        % Value changed function: P4_2
        function P4_2Changed(app, event)
            value = app.P4_2.Value;
            
        end

        % Value changed function: P5_2
        function P5_2Changed(app, event)
            value = app.P5_2.Value;
            
        end

        % Value changed function: P6_2
        function P6_2Changed(app, event)
            value = app.P6_2.Value;
            
        end

        % Value changed function: P7_2
        function P7_2Changed(app, event)
            value = app.P7_2.Value;
            
        end

        % Value changed function: P8_2
        function P8_2Changed(app, event)
            value = app.P8_2.Value;
            
        end

        % Value changed function: P1_3
        function P1_3Changed(app, event)
            value = app.P1_3.Value;
            
        end

        % Value changed function: P2_3
        function P2_3Changed(app, event)
            value = app.P2_3.Value;
            
        end

        % Value changed function: P3_3
        function P3_3Changed(app, event)
            value = app.P3_3.Value;
            
        end

        % Value changed function: P4_3
        function P4_3Changed(app, event)
            value = app.P4_3.Value;
            
        end

        % Value changed function: P5_3
        function P5_3Changed(app, event)
            value = app.P5_3.Value;
            
        end

        % Value changed function: P6_3
        function P6_3Changed(app, event)
            value = app.P6_3.Value;
            
        end

        % Value changed function: P7_3
        function P7_3Changed(app, event)
            value = app.P7_3.Value;
            
        end

        % Value changed function: P8_3
        function P8_3Changed(app, event)
            value = app.P8_3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartProcessButton = uibutton(app.UIFigure, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProc, true);
            app.StartProcessButton.Text = 'Start Process';
            app.OpenFileLocationButton = uibutton(app.UIFigure, 'push');
            app.OpenFileLocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileLocationButtonPushed, true);
            app.OpenFileLocationButton.Text = 'Open File Location';
            app.FileLoc = uitextarea(app.UIFigure);
            app.FileLoc.Editable = 'off';
            app.FileLoc.Value = {'G:\My Drive\Exp_data_new\test'};
            app.SaveFileLocationLabel = uilabel(app.UIFigure);
            app.SaveFileLocationLabel.Text = 'Save File Location';
            app.StopProcessButton = uibutton(app.UIFigure, 'push');
            app.StopProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StopProc, true);
            app.StopProcessButton.Text = 'Stop Process';
            app.HPGUI_Group = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.HPGUI_Group);
            app.GeneralTab.Title = 'General';
            app.NumberoflogpointsduringstressIandTLabel = uilabel(app.GeneralTab);
            app.NumberoflogpointsduringstressIandTLabel.Text = 'Number of log points during stress (I and T)';
            app.Itpoints = uieditfield(app.GeneralTab, 'numeric');
            app.Itpoints.Value = 10;
            app.StressVoltageVLabel_4 = uilabel(app.GeneralTab);
            app.StressVoltageVLabel_4.Text = 'Stress Voltage [V]';
            app.Bias_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.ImpedanceAnalyzerCVLabel_gnl = uilabel(app.GeneralTab);
            app.ImpedanceAnalyzerCVLabel_gnl.Text = 'Impedance Analyzer (CV)';
            app.Freq_unit_gnl = uidropdown(app.GeneralTab);
            app.Freq_unit_gnl.Items = {'KHz', 'MHz'};
            app.Freq_unit_gnl.Value = 'MHz';
            app.FrequencyLabel_4 = uilabel(app.GeneralTab);
            app.FrequencyLabel_4.Text = 'Frequency';
            app.Frequency_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.Frequency_gnl.Value = 1;
            app.PositiveVoltageVLabel_4 = uilabel(app.GeneralTab);
            app.PositiveVoltageVLabel_4.Text = 'Positive Voltage (V)';
            app.VPos_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.VPos_gnl.Value = 7;
            app.NegativeVoltageVLabel_4 = uilabel(app.GeneralTab);
            app.NegativeVoltageVLabel_4.Text = 'Negative Voltage (V)';
            app.VNeg_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.VNeg_gnl.Value = -7;
            app.VACmVpkpkEditFieldLabel_4 = uilabel(app.GeneralTab);
            app.VACmVpkpkEditFieldLabel_4.Text = 'VAC [mV(pk-pk)]';
            app.OSC_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.OSC_gnl.Value = 100;
            app.SweepSwitchLabel_4 = uilabel(app.GeneralTab);
            app.SweepSwitchLabel_4.Text = 'Sweep';
            app.Sweep_gnl = uiswitch(app.GeneralTab, 'rocker');
            app.Sweep_gnl.Items = {'Down', 'Up'};
            app.Sweep_gnl.Value = 'Down';
            app.StepVoltagemVLabel_4 = uilabel(app.GeneralTab);
            app.StepVoltagemVLabel_4.Text = 'Step Voltage (mV)';
            app.VStep_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.VStep_gnl.Value = 100;
            app.ofAveragesDropDownLabel_4 = uilabel(app.GeneralTab);
            app.ofAveragesDropDownLabel_4.Text = '# of Averages';
            app.NOA_gnl = uidropdown(app.GeneralTab);
            app.NOA_gnl.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.NOA_gnl.Value = '2';
            app.IntegrationTimeDropDownLabel_4 = uilabel(app.GeneralTab);
            app.IntegrationTimeDropDownLabel_4.Text = 'Integration Time';
            app.ITM_gnl = uidropdown(app.GeneralTab);
            app.ITM_gnl.Items = {'500 us', '5 ms', '100 ms'};
            app.ITM_gnl.Value = '5 ms';
            app.ExperimentalsettingsLabel = uilabel(app.GeneralTab);
            app.ExperimentalsettingsLabel.Text = 'Experimental settings';
            app.t_inc_unit_gnl = uidropdown(app.GeneralTab);
            app.t_inc_unit_gnl.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_inc_unit_gnl.Value = 'sec';
            app.StresstimeLabel = uilabel(app.GeneralTab);
            app.StresstimeLabel.Text = 'Stress time';
            app.dt_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.dt_gnl.Value = 5;
            app.TotalnumberofcyclesLabel = uilabel(app.GeneralTab);
            app.TotalnumberofcyclesLabel.Text = 'Total number of cycles';
            app.Iter_tot_gnl = uieditfield(app.GeneralTab, 'numeric');
            app.Iter_tot_gnl.Value = 1;
            app.TemperatureramplogintervalsLabel = uilabel(app.GeneralTab);
            app.TemperatureramplogintervalsLabel.Text = 'Temperature ramp log interval (s)';
            app.T_log_ramp_step = uieditfield(app.GeneralTab, 'numeric');
            app.T_log_ramp_step.Value = 30;
            app.LogsettingsLabel = uilabel(app.GeneralTab);
            app.LogsettingsLabel.Text = 'Log settings';
            app.ConnectionPanel = uipanel(app.GeneralTab);
            app.ConnectionPanel.Title = 'Connection Panel';
            app.PowerSwitchLabel = uilabel(app.ConnectionPanel);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.ConnectionPanel, 'toggle');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @TogglePower, true);
            app.Hotplate1LampLabel = uilabel(app.ConnectionPanel);
            app.Hotplate1LampLabel.Text = 'Hotplate 1';
            app.StatusLampHP1 = uilamp(app.ConnectionPanel);
            app.Hotplate2Label = uilabel(app.ConnectionPanel);
            app.Hotplate2Label.Text = 'Hotplate 2';
            app.StatusLampHP2 = uilamp(app.ConnectionPanel);
            app.Hotplate3Label = uilabel(app.ConnectionPanel);
            app.Hotplate3Label.Text = 'Hotplate 3';
            app.StatusLampHP3 = uilamp(app.ConnectionPanel);
            app.Arduino1Label = uilabel(app.ConnectionPanel);
            app.Arduino1Label.Text = 'Arduino 1';
            app.StatusLampArd1 = uilamp(app.ConnectionPanel);
            app.Arduino2Label = uilabel(app.ConnectionPanel);
            app.Arduino2Label.Text = 'Arduino 2';
            app.StatusLampArd2 = uilamp(app.ConnectionPanel);
            app.Arduino3Label = uilabel(app.ConnectionPanel);
            app.Arduino3Label.Text = 'Arduino 3';
            app.StatusLampArd3 = uilamp(app.ConnectionPanel);
            app.KeithleyLabel = uilabel(app.ConnectionPanel);
            app.KeithleyLabel.Text = 'Keithley';
            app.StatusLampKeithley = uilamp(app.ConnectionPanel);
            app.ImpedanceAnalyzerLabel = uilabel(app.ConnectionPanel);
            app.ImpedanceAnalyzerLabel.Text = 'Impedance Analyzer';
            app.StatusLampImpedance = uilamp(app.ConnectionPanel);
            app.HP1_Tab = uitab(app.HPGUI_Group);
            app.HP1_Tab.Title = 'Hotplate 1';
            app.HP1_Group = uitabgroup(app.HP1_Tab);
            app.Input = uitab(app.HP1_Group);
            app.Input.Title = 'Input';
            app.ExperimentalFittingLabel_1 = uilabel(app.Input);
            app.ExperimentalFittingLabel_1.Text = 'Experimental/Fitting:';
            app.t_offset_unit_1 = uidropdown(app.Input);
            app.t_offset_unit_1.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_1.Value = 'sec';
            app.THKnmEditFieldLabel = uilabel(app.Input);
            app.THKnmEditFieldLabel.Text = 'THK [nm]';
            app.THK_1 = uieditfield(app.Input, 'numeric');
            app.THK_1.Value = 76.96;
            app.HeatTempCLabel = uilabel(app.Input);
            app.HeatTempCLabel.Text = 'Heat Temp [C]';
            app.TempH_1 = uieditfield(app.Input, 'numeric');
            app.TempH_1.Value = 26;
            app.TimeOffsetLabel = uilabel(app.Input);
            app.TimeOffsetLabel.Text = 'Time Offset';
            app.TimeOffset_1 = uieditfield(app.Input, 'numeric');
            app.IterationsMeasureLabel = uilabel(app.Input);
            app.IterationsMeasureLabel.Text = '# Iterations/Measure';
            app.IterM_1 = uieditfield(app.Input, 'numeric');
            app.IterM_1.Value = 1;
            app.PreStressTimeminLabel = uilabel(app.Input);
            app.PreStressTimeminLabel.Text = 'PreStress Time [min]';
            app.PreBiasTime_1 = uieditfield(app.Input, 'numeric');
            app.CoolTempCLabel = uilabel(app.Input);
            app.CoolTempCLabel.Text = 'Cool Temp [C]';
            app.TempC_1 = uieditfield(app.Input, 'numeric');
            app.TempC_1.Value = 26;
            app.P1Lamp_1 = uilamp(app.Input);
            app.P2Lamp_1 = uilamp(app.Input);
            app.P3Lamp_1 = uilamp(app.Input);
            app.P4Lamp_1 = uilamp(app.Input);
            app.P1_1 = uibutton(app.Input, 'state');
            app.P1_1.ValueChangedFcn = createCallbackFcn(app, @P1_1Changed, true);
            app.P1_1.Text = 'OFF';
            app.P2_1 = uibutton(app.Input, 'state');
            app.P2_1.ValueChangedFcn = createCallbackFcn(app, @P2_1Changed, true);
            app.P2_1.Text = 'OFF';
            app.P3_1 = uibutton(app.Input, 'state');
            app.P3_1.ValueChangedFcn = createCallbackFcn(app, @P3_1Changed, true);
            app.P3_1.Text = 'OFF';
            app.P4_1 = uibutton(app.Input, 'state');
            app.P4_1.ValueChangedFcn = createCallbackFcn(app, @P4_1Changed, true);
            app.P4_1.Text = 'OFF';
            app.ArduinoPinLabel_1 = uilabel(app.Input);
            app.ArduinoPinLabel_1.Text = 'Arduino Pin #:';
            app.AP1_1 = uieditfield(app.Input, 'numeric');
            app.AP1_1.Value = 1;
            app.AP2_1 = uieditfield(app.Input, 'numeric');
            app.AP2_1.Value = 2;
            app.AP3_1 = uieditfield(app.Input, 'numeric');
            app.AP3_1.Value = 3;
            app.AP4_1 = uieditfield(app.Input, 'numeric');
            app.AP4_1.Value = 4;
            app.ArduinoPinLabel_2 = uilabel(app.Input);
            app.ArduinoPinLabel_2.Text = 'Arduino Pin #:';
            app.P5Lamp_1 = uilamp(app.Input);
            app.P6Lamp_1 = uilamp(app.Input);
            app.P7Lamp_1 = uilamp(app.Input);
            app.P8Lamp_1 = uilamp(app.Input);
            app.P5_1 = uibutton(app.Input, 'state');
            app.P5_1.ValueChangedFcn = createCallbackFcn(app, @P5_1Changed, true);
            app.P5_1.Text = 'OFF';
            app.P6_1 = uibutton(app.Input, 'state');
            app.P6_1.ValueChangedFcn = createCallbackFcn(app, @P6_1Changed, true);
            app.P6_1.Text = 'OFF';
            app.P7_1 = uibutton(app.Input, 'state');
            app.P7_1.ValueChangedFcn = createCallbackFcn(app, @P7_1Changed, true);
            app.P7_1.Text = 'OFF';
            app.P8_1 = uibutton(app.Input, 'state');
            app.P8_1.ValueChangedFcn = createCallbackFcn(app, @P8_1Changed, true);
            app.P8_1.Text = 'OFF';
            app.AP5_1 = uieditfield(app.Input, 'numeric');
            app.AP5_1.Value = 5;
            app.AP6_1 = uieditfield(app.Input, 'numeric');
            app.AP6_1.Value = 6;
            app.AP7_1 = uieditfield(app.Input, 'numeric');
            app.AP7_1.Value = 7;
            app.AP8_1 = uieditfield(app.Input, 'numeric');
            app.AP8_1.Value = 8;
            app.PinStatesLabel_1 = uilabel(app.Input);
            app.PinStatesLabel_1.Text = 'Pin States';
            app.P1Label_1 = uilabel(app.Input);
            app.P1Label_1.Text = '1';
            app.P2Label_1 = uilabel(app.Input);
            app.P2Label_1.Text = '2';
            app.P3Label_1 = uilabel(app.Input);
            app.P3Label_1.Text = '3';
            app.P4Label_1 = uilabel(app.Input);
            app.P4Label_1.Text = '4';
            app.P5Label_1 = uilabel(app.Input);
            app.P5Label_1.Text = '5';
            app.P6Label_1 = uilabel(app.Input);
            app.P6Label_1.Text = '6';
            app.P7Label_1 = uilabel(app.Input);
            app.P7Label_1.Text = '7';
            app.P8Label_1 = uilabel(app.Input);
            app.P8Label_1.Text = '8';
            app.PinReset_1 = uibutton(app.Input, 'push');
            app.PinReset_1.ButtonPushedFcn = createCallbackFcn(app, @PinReset_1ButtonPushed, true);
            app.PinReset_1.Text = 'Pin Reset';
            app.SwitchAllLabel = uilabel(app.Input);
            app.SwitchAllLabel.Text = 'Switch All';
            app.SwitchAll_HP1 = uiswitch(app.Input, 'toggle');
            app.SwitchAll_HP1.ValueChangedFcn = createCallbackFcn(app, @ToggleAllPinsHP1, true);
            app.SaveDataAsTextAreaLabel_2 = uilabel(app.Input);
            app.SaveDataAsTextAreaLabel_2.Text = 'Save Name, unit 1:';
            app.DataFileName_MU1 = uitextarea(app.Input);
            app.DataFileName_MU1.Value = {'test_MU1'};
            app.CV1Tab_1 = uitab(app.HP1_Group);
            app.CV1Tab_1.Title = 'CV1';
            app.Export_CV_1 = uibutton(app.CV1Tab_1, 'push');
            app.Export_CV_1.ButtonPushedFcn = createCallbackFcn(app, @Export_CV_1Pushed, true);
            app.Export_CV_1.Text = 'Export Figure';
            app.CV1_1 = uiaxes(app.CV1Tab_1);
            title(app.CV1_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_1, 'Voltage (V)')
            ylabel(app.CV1_1, 'Capacitance (pF)')
            app.Normalize_1 = uibutton(app.CV1Tab_1, 'state');
            app.Normalize_1.Text = 'Normalize';
            app.CV2_1 = uiaxes(app.CV1Tab_1);
            title(app.CV2_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_1, 'Voltage (V)')
            ylabel(app.CV2_1, 'Capacitance (pF)')
            app.CV3_1 = uiaxes(app.CV1Tab_1);
            title(app.CV3_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_1, 'Voltage (V)')
            ylabel(app.CV3_1, 'Capacitance (pF)')
            app.CV4_1 = uiaxes(app.CV1Tab_1);
            title(app.CV4_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_1, 'Voltage (V)')
            ylabel(app.CV4_1, 'Capacitance (pF)')
            app.CV2Tab_1 = uitab(app.HP1_Group);
            app.CV2Tab_1.Title = 'CV2';
            app.Export_CV_2_1 = uibutton(app.CV2Tab_1, 'push');
            app.Export_CV_2_1.Text = 'Export Figure';
            app.CV5_1 = uiaxes(app.CV2Tab_1);
            title(app.CV5_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_1, 'Voltage (V)')
            ylabel(app.CV5_1, 'Capacitance (pF)')
            app.Normalize_2_1 = uibutton(app.CV2Tab_1, 'state');
            app.Normalize_2_1.Text = 'Normalize';
            app.CV6_1 = uiaxes(app.CV2Tab_1);
            title(app.CV6_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_1, 'Voltage (V)')
            ylabel(app.CV6_1, 'Capacitance (pF)')
            app.CV7_1 = uiaxes(app.CV2Tab_1);
            title(app.CV7_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_1, 'Voltage (V)')
            ylabel(app.CV7_1, 'Capacitance (pF)')
            app.CV8_1 = uiaxes(app.CV2Tab_1);
            title(app.CV8_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_1, 'Voltage (V)')
            ylabel(app.CV8_1, 'Capacitance (pF)')
            app.VFBFit1Tab_1 = uitab(app.HP1_Group);
            app.VFBFit1Tab_1.Title = 'VFB Fit 1';
            app.byC2_1_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_1_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_1, 'Voltage (V)')
            ylabel(app.byC2_1_1, 'd^2(1/C^2)/d^2V')
            app.byC2_2_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_2_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_1, 'Voltage (V)')
            ylabel(app.byC2_2_1, 'd^2(1/C^2)/d^2V')
            app.byC2_3_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_3_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_1, 'Voltage (V)')
            ylabel(app.byC2_3_1, 'd^2(1/C^2)/d^2V')
            app.byC2_4_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_4_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_1, 'Voltage (V)')
            ylabel(app.byC2_4_1, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_1 = uibutton(app.VFBFit1Tab_1, 'push');
            app.Export_byC2_1.ButtonPushedFcn = createCallbackFcn(app, @Export_byC2_1Pushed, true);
            app.Export_byC2_1.Text = 'Export Figure';
            app.DerPeaks_1_1 = uibutton(app.VFBFit1Tab_1, 'state');
            app.DerPeaks_1_1.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_1_1Pressed, true);
            app.DerPeaks_1_1.Text = 'Derivative Peaks';
            app.DerPeaks_1_1.Value = true;
            app.UpdateFit_1 = uibutton(app.VFBFit1Tab_1, 'push');
            app.UpdateFit_1.ButtonPushedFcn = createCallbackFcn(app, @UpdateFit_1ButtonPushed, true);
            app.UpdateFit_1.Text = 'Update Fit';
            app.CFitMinEditFieldLabel = uilabel(app.VFBFit1Tab_1);
            app.CFitMinEditFieldLabel.Text = 'C Fit Min:';
            app.CFitMin_1 = uieditfield(app.VFBFit1Tab_1, 'numeric');
            app.CFitMin_1.Value = 5;
            app.FitRegion_1 = uibutton(app.VFBFit1Tab_1, 'state');
            app.FitRegion_1.Text = 'Fit Region';
            app.SmoothCheckBox_1 = uicheckbox(app.VFBFit1Tab_1);
            app.SmoothCheckBox_1.Text = 'Smooth';
            app.CFitMaxEditFieldLabel = uilabel(app.VFBFit1Tab_1);
            app.CFitMaxEditFieldLabel.Text = 'C Fit Max:';
            app.CFitMax_1 = uieditfield(app.VFBFit1Tab_1, 'numeric');
            app.CFitMax_1.Value = 12;
            app.VFBFit2Tab_1 = uitab(app.HP1_Group);
            app.VFBFit2Tab_1.Title = 'VFB Fit 2';
            app.byC2_5_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_5_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_1, 'Voltage (V)')
            ylabel(app.byC2_5_1, 'd^2(1/C^2)/d^2V')
            app.byC2_6_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_6_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_1, 'Voltage (V)')
            ylabel(app.byC2_6_1, 'd^2(1/C^2)/d^2V')
            app.byC2_7_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_7_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_1, 'Voltage (V)')
            ylabel(app.byC2_7_1, 'd^2(1/C^2)/d^2V')
            app.byC2_8_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_8_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_1, 'Voltage (V)')
            ylabel(app.byC2_8_1, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_1 = uibutton(app.VFBFit2Tab_1, 'push');
            app.Export_byC2_2_1.Text = 'Export Figure';
            app.DerPeaks_1_2 = uibutton(app.VFBFit2Tab_1, 'state');
            app.DerPeaks_1_2.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_1_2Pressed, true);
            app.DerPeaks_1_2.Text = 'Derivative Peaks';
            app.DerPeaks_1_2.Value = true;
            app.SmoothCheckBox_2_1 = uicheckbox(app.VFBFit2Tab_1);
            app.SmoothCheckBox_2_1.Text = 'Smooth';
            app.UpdateFit_2_1 = uibutton(app.VFBFit2Tab_1, 'push');
            app.UpdateFit_2_1.Text = 'Update Fit';
            app.FitRegion_2_1 = uibutton(app.VFBFit2Tab_1, 'state');
            app.FitRegion_2_1.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_2 = uilabel(app.VFBFit2Tab_1);
            app.CFitMaxEditFieldLabel_2.Text = 'C Fit Max:';
            app.CFitMax_2_1 = uieditfield(app.VFBFit2Tab_1, 'numeric');
            app.CFitMax_2_1.Value = 12;
            app.CFitMinEditFieldLabel_2 = uilabel(app.VFBFit2Tab_1);
            app.CFitMinEditFieldLabel_2.Text = 'C Fit Min:';
            app.CFitMin_2_1 = uieditfield(app.VFBFit2Tab_1, 'numeric');
            app.CFitMin_2_1.Value = 5;
            app.ItTab_1 = uitab(app.HP1_Group);
            app.ItTab_1.Title = 'I-t';
            app.It_1 = uiaxes(app.ItTab_1);
            title(app.It_1, 'Current Vs. Time')
            xlabel(app.It_1, 'Time (hr)')
            ylabel(app.It_1, 'Current (A)')
            app.Export_It_1 = uibutton(app.ItTab_1, 'push');
            app.Export_It_1.ButtonPushedFcn = createCallbackFcn(app, @Export_It_1Button, true);
            app.Export_It_1.Text = 'Export Figure';
            app.VFBvsTimeTab_1 = uitab(app.HP1_Group);
            app.VFBvsTimeTab_1.Title = 'VFB vs. Time';
            app.ExportVFBtime_1 = uibutton(app.VFBvsTimeTab_1, 'push');
            app.ExportVFBtime_1.ButtonPushedFcn = createCallbackFcn(app, @ExportVFBtime_1Pushed, true);
            app.ExportVFBtime_1.Text = 'Export Figure';
            app.VFBtime_1 = uiaxes(app.VFBvsTimeTab_1);
            title(app.VFBtime_1, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_1, 'Time')
            ylabel(app.VFBtime_1, 'Flatband Voltage (V)')
            app.TempvsTimeTab_1 = uitab(app.HP1_Group);
            app.TempvsTimeTab_1.Title = 'Temperature vs. Time';
            app.TempTime_1 = uiaxes(app.TempvsTimeTab_1);
            title(app.TempTime_1, 'Temperature Vs. Time')
            xlabel(app.TempTime_1, 'Time (hr)')
            ylabel(app.TempTime_1, 'Temperature (C)')
            app.Export_TempTime_1 = uibutton(app.TempvsTimeTab_1, 'push');
            app.Export_TempTime_1.ButtonPushedFcn = createCallbackFcn(app, @Export_TempTime_1Button, true);
            app.Export_TempTime_1.Text = 'Export Figure';
            app.HP2_Tab = uitab(app.HPGUI_Group);
            app.HP2_Tab.Title = 'Hotplate 2';
            app.HP1_Group_2 = uitabgroup(app.HP2_Tab);
            app.Input_2 = uitab(app.HP1_Group_2);
            app.Input_2.Title = 'Input';
            app.ExperimentalFittingLabel_2 = uilabel(app.Input_2);
            app.ExperimentalFittingLabel_2.Text = 'Experimental/Fitting:';
            app.t_offset_unit_2 = uidropdown(app.Input_2);
            app.t_offset_unit_2.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_2.Value = 'sec';
            app.THKnmEditFieldLabel_4 = uilabel(app.Input_2);
            app.THKnmEditFieldLabel_4.Text = 'THK [nm]';
            app.THK_2 = uieditfield(app.Input_2, 'numeric');
            app.THK_2.Value = 76.96;
            app.HeatTempCLabel_4 = uilabel(app.Input_2);
            app.HeatTempCLabel_4.Text = 'Heat Temp [C]';
            app.TempH_2 = uieditfield(app.Input_2, 'numeric');
            app.TempH_2.Value = 26;
            app.TimeOffsetLabel_4 = uilabel(app.Input_2);
            app.TimeOffsetLabel_4.Text = 'Time Offset';
            app.TimeOffset_2 = uieditfield(app.Input_2, 'numeric');
            app.IterationsMeasureLabel_4 = uilabel(app.Input_2);
            app.IterationsMeasureLabel_4.Text = '# Iterations/Measure';
            app.IterM_2 = uieditfield(app.Input_2, 'numeric');
            app.IterM_2.Value = 1;
            app.PreStressTimeminLabel_4 = uilabel(app.Input_2);
            app.PreStressTimeminLabel_4.Text = 'PreStress Time [min]';
            app.PreBiasTime_2 = uieditfield(app.Input_2, 'numeric');
            app.CoolTempCLabel_4 = uilabel(app.Input_2);
            app.CoolTempCLabel_4.Text = 'Cool Temp [C]';
            app.TempC_2 = uieditfield(app.Input_2, 'numeric');
            app.TempC_2.Value = 26;
            app.P1Lamp_2 = uilamp(app.Input_2);
            app.P2Lamp_2 = uilamp(app.Input_2);
            app.P3Lamp_2 = uilamp(app.Input_2);
            app.P4Lamp_2 = uilamp(app.Input_2);
            app.P1_2 = uibutton(app.Input_2, 'state');
            app.P1_2.ValueChangedFcn = createCallbackFcn(app, @P1_2Changed, true);
            app.P1_2.Text = 'OFF';
            app.P2_2 = uibutton(app.Input_2, 'state');
            app.P2_2.ValueChangedFcn = createCallbackFcn(app, @P2_2Changed, true);
            app.P2_2.Text = 'OFF';
            app.P3_2 = uibutton(app.Input_2, 'state');
            app.P3_2.ValueChangedFcn = createCallbackFcn(app, @P3_2Changed, true);
            app.P3_2.Text = 'OFF';
            app.P4_2 = uibutton(app.Input_2, 'state');
            app.P4_2.ValueChangedFcn = createCallbackFcn(app, @P4_2Changed, true);
            app.P4_2.Text = 'OFF';
            app.ArduinoPinLabel_1_2 = uilabel(app.Input_2);
            app.ArduinoPinLabel_1_2.Text = 'Arduino Pin #:';
            app.AP1_2 = uieditfield(app.Input_2, 'numeric');
            app.AP1_2.Value = 1;
            app.AP2_2 = uieditfield(app.Input_2, 'numeric');
            app.AP2_2.Value = 2;
            app.AP3_2 = uieditfield(app.Input_2, 'numeric');
            app.AP3_2.Value = 3;
            app.AP4_2 = uieditfield(app.Input_2, 'numeric');
            app.AP4_2.Value = 4;
            app.ArduinoPinLabel_2_2 = uilabel(app.Input_2);
            app.ArduinoPinLabel_2_2.Text = 'Arduino Pin #:';
            app.P5Lamp_2 = uilamp(app.Input_2);
            app.P6Lamp_2 = uilamp(app.Input_2);
            app.P7Lamp_2 = uilamp(app.Input_2);
            app.P8Lamp_2 = uilamp(app.Input_2);
            app.P5_2 = uibutton(app.Input_2, 'state');
            app.P5_2.ValueChangedFcn = createCallbackFcn(app, @P5_2Changed, true);
            app.P5_2.Text = 'OFF';
            app.P6_2 = uibutton(app.Input_2, 'state');
            app.P6_2.ValueChangedFcn = createCallbackFcn(app, @P6_2Changed, true);
            app.P6_2.Text = 'OFF';
            app.P7_2 = uibutton(app.Input_2, 'state');
            app.P7_2.ValueChangedFcn = createCallbackFcn(app, @P7_2Changed, true);
            app.P7_2.Text = 'OFF';
            app.P8_2 = uibutton(app.Input_2, 'state');
            app.P8_2.ValueChangedFcn = createCallbackFcn(app, @P8_2Changed, true);
            app.P8_2.Text = 'OFF';
            app.AP5_2 = uieditfield(app.Input_2, 'numeric');
            app.AP5_2.Value = 5;
            app.AP6_2 = uieditfield(app.Input_2, 'numeric');
            app.AP6_2.Value = 6;
            app.AP7_2 = uieditfield(app.Input_2, 'numeric');
            app.AP7_2.Value = 7;
            app.AP8_2 = uieditfield(app.Input_2, 'numeric');
            app.AP8_2.Value = 8;
            app.PinStatesLabel_2 = uilabel(app.Input_2);
            app.PinStatesLabel_2.Text = 'Pin States';
            app.P1Label_2 = uilabel(app.Input_2);
            app.P1Label_2.Text = '1';
            app.P2Label_2 = uilabel(app.Input_2);
            app.P2Label_2.Text = '2';
            app.P3Label_2 = uilabel(app.Input_2);
            app.P3Label_2.Text = '3';
            app.P4Label_2 = uilabel(app.Input_2);
            app.P4Label_2.Text = '4';
            app.P5Label_2 = uilabel(app.Input_2);
            app.P5Label_2.Text = '5';
            app.P6Label_2 = uilabel(app.Input_2);
            app.P6Label_2.Text = '6';
            app.P7Label_2 = uilabel(app.Input_2);
            app.P7Label_2.Text = '7';
            app.P8Label_2 = uilabel(app.Input_2);
            app.P8Label_2.Text = '8';
            app.PinReset_2 = uibutton(app.Input_2, 'push');
            app.PinReset_2.Text = 'Pin Reset';
            app.SwitchAllLabel_4 = uilabel(app.Input_2);
            app.SwitchAllLabel_4.Text = 'Switch All';
            app.SwitchAll_HP2 = uiswitch(app.Input_2, 'toggle');
            app.SwitchAll_HP2.ValueChangedFcn = createCallbackFcn(app, @ToggleAllPinsHP2, true);
            app.SaveDataAsTextAreaLabel_1_2 = uilabel(app.Input_2);
            app.SaveDataAsTextAreaLabel_1_2.Text = 'Save Name, unit 1:';
            app.DataFileName_MU2 = uitextarea(app.Input_2);
            app.DataFileName_MU2.Value = {'test_MU1'};
            app.CV1Tab_2 = uitab(app.HP1_Group_2);
            app.CV1Tab_2.Title = 'CV1';
            app.Export_CV_2 = uibutton(app.CV1Tab_2, 'push');
            app.Export_CV_2.Text = 'Export Figure';
            app.CV1_2 = uiaxes(app.CV1Tab_2);
            title(app.CV1_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_2, 'Voltage (V)')
            ylabel(app.CV1_2, 'Capacitance (pF)')
            app.Normalize_2 = uibutton(app.CV1Tab_2, 'state');
            app.Normalize_2.Text = 'Normalize';
            app.CV2_2 = uiaxes(app.CV1Tab_2);
            title(app.CV2_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_2, 'Voltage (V)')
            ylabel(app.CV2_2, 'Capacitance (pF)')
            app.CV3_2 = uiaxes(app.CV1Tab_2);
            title(app.CV3_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_2, 'Voltage (V)')
            ylabel(app.CV3_2, 'Capacitance (pF)')
            app.CV4_2 = uiaxes(app.CV1Tab_2);
            title(app.CV4_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_2, 'Voltage (V)')
            ylabel(app.CV4_2, 'Capacitance (pF)')
            app.CV2Tab_2 = uitab(app.HP1_Group_2);
            app.CV2Tab_2.Title = 'CV2';
            app.Export_CV_2_2 = uibutton(app.CV2Tab_2, 'push');
            app.Export_CV_2_2.Text = 'Export Figure';
            app.CV5_2 = uiaxes(app.CV2Tab_2);
            title(app.CV5_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_2, 'Voltage (V)')
            ylabel(app.CV5_2, 'Capacitance (pF)')
            app.Normalize_2_2 = uibutton(app.CV2Tab_2, 'state');
            app.Normalize_2_2.Text = 'Normalize';
            app.CV6_2 = uiaxes(app.CV2Tab_2);
            title(app.CV6_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_2, 'Voltage (V)')
            ylabel(app.CV6_2, 'Capacitance (pF)')
            app.CV7_2 = uiaxes(app.CV2Tab_2);
            title(app.CV7_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_2, 'Voltage (V)')
            ylabel(app.CV7_2, 'Capacitance (pF)')
            app.CV8_2 = uiaxes(app.CV2Tab_2);
            title(app.CV8_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_2, 'Voltage (V)')
            ylabel(app.CV8_2, 'Capacitance (pF)')
            app.VFBFit1Tab_2 = uitab(app.HP1_Group_2);
            app.VFBFit1Tab_2.Title = 'VFB Fit 1';
            app.byC2_1_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_1_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_2, 'Voltage (V)')
            ylabel(app.byC2_1_2, 'd^2(1/C^2)/d^2V')
            app.byC2_2_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_2_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_2, 'Voltage (V)')
            ylabel(app.byC2_2_2, 'd^2(1/C^2)/d^2V')
            app.byC2_3_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_3_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_2, 'Voltage (V)')
            ylabel(app.byC2_3_2, 'd^2(1/C^2)/d^2V')
            app.byC2_4_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_4_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_2, 'Voltage (V)')
            ylabel(app.byC2_4_2, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2 = uibutton(app.VFBFit1Tab_2, 'push');
            app.Export_byC2_2.Text = 'Export Figure';
            app.DerPeaks_2_1 = uibutton(app.VFBFit1Tab_2, 'state');
            app.DerPeaks_2_1.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_2_1Pressed, true);
            app.DerPeaks_2_1.Text = 'Derivative Peaks';
            app.DerPeaks_2_1.Value = true;
            app.UpdateFit_2 = uibutton(app.VFBFit1Tab_2, 'push');
            app.UpdateFit_2.Text = 'Update Fit';
            app.CFitMinEditFieldLabel_7 = uilabel(app.VFBFit1Tab_2);
            app.CFitMinEditFieldLabel_7.Text = 'C Fit Min:';
            app.CFitMin_2 = uieditfield(app.VFBFit1Tab_2, 'numeric');
            app.CFitMin_2.Value = 5;
            app.FitRegion_2 = uibutton(app.VFBFit1Tab_2, 'state');
            app.FitRegion_2.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_7 = uilabel(app.VFBFit1Tab_2);
            app.CFitMaxEditFieldLabel_7.Text = 'C Fit Max:';
            app.CFitMax_2 = uieditfield(app.VFBFit1Tab_2, 'numeric');
            app.CFitMax_2.Value = 12;
            app.SmoothCheckBox_1_2 = uicheckbox(app.VFBFit1Tab_2);
            app.SmoothCheckBox_1_2.Text = 'Smooth';
            app.VFBFit2Tab_2 = uitab(app.HP1_Group_2);
            app.VFBFit2Tab_2.Title = 'VFB Fit 2';
            app.byC2_5_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_5_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_2, 'Voltage (V)')
            ylabel(app.byC2_5_2, 'd^2(1/C^2)/d^2V')
            app.byC2_6_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_6_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_2, 'Voltage (V)')
            ylabel(app.byC2_6_2, 'd^2(1/C^2)/d^2V')
            app.byC2_7_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_7_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_2, 'Voltage (V)')
            ylabel(app.byC2_7_2, 'd^2(1/C^2)/d^2V')
            app.byC2_8_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_8_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_2, 'Voltage (V)')
            ylabel(app.byC2_8_2, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_2 = uibutton(app.VFBFit2Tab_2, 'push');
            app.Export_byC2_2_2.Text = 'Export Figure';
            app.DerPeaks_2_2 = uibutton(app.VFBFit2Tab_2, 'state');
            app.DerPeaks_2_2.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_2_2Pressed, true);
            app.DerPeaks_2_2.Text = 'Derivative Peaks';
            app.DerPeaks_2_2.Value = true;
            app.SmoothCheckBox_2_2 = uicheckbox(app.VFBFit2Tab_2);
            app.SmoothCheckBox_2_2.Text = 'Smooth';
            app.UpdateFit_2_2 = uibutton(app.VFBFit2Tab_2, 'push');
            app.UpdateFit_2_2.Text = 'Update Fit';
            app.FitRegion_2_4 = uibutton(app.VFBFit2Tab_2, 'state');
            app.FitRegion_2_4.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_8 = uilabel(app.VFBFit2Tab_2);
            app.CFitMaxEditFieldLabel_8.Text = 'C Fit Max:';
            app.CFitMax_2_2 = uieditfield(app.VFBFit2Tab_2, 'numeric');
            app.CFitMax_2_2.Value = 12;
            app.CFitMinEditFieldLabel_8 = uilabel(app.VFBFit2Tab_2);
            app.CFitMinEditFieldLabel_8.Text = 'C Fit Min:';
            app.CFitMin_2_2 = uieditfield(app.VFBFit2Tab_2, 'numeric');
            app.CFitMin_2_2.Value = 5;
            app.ItTab_2 = uitab(app.HP1_Group_2);
            app.ItTab_2.Title = 'I-t';
            app.It_2 = uiaxes(app.ItTab_2);
            title(app.It_2, 'Current Vs. Time')
            xlabel(app.It_2, 'Time (hr)')
            ylabel(app.It_2, 'Current (A)')
            app.Export_It_2 = uibutton(app.ItTab_2, 'push');
            app.Export_It_2.Text = 'Export Figure';
            app.VFBvsTimeTab_2 = uitab(app.HP1_Group_2);
            app.VFBvsTimeTab_2.Title = 'VFB vs. Time';
            app.ExportVFBtime_2 = uibutton(app.VFBvsTimeTab_2, 'push');
            app.ExportVFBtime_2.Text = 'Export Figure';
            app.VFBtime_2 = uiaxes(app.VFBvsTimeTab_2);
            title(app.VFBtime_2, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_2, 'Time')
            ylabel(app.VFBtime_2, 'Flatband Voltage (V)')
            app.TempvsTimeTab_2 = uitab(app.HP1_Group_2);
            app.TempvsTimeTab_2.Title = 'Temperature vs. Time';
            app.TempTime_2 = uiaxes(app.TempvsTimeTab_2);
            title(app.TempTime_2, 'Temperature Vs. Time')
            xlabel(app.TempTime_2, 'Time (hr)')
            ylabel(app.TempTime_2, 'Temperature (C)')
            app.Export_TempTime_2 = uibutton(app.TempvsTimeTab_2, 'push');
            app.Export_TempTime_2.Text = 'Export Figure';
            app.HP3_Tab = uitab(app.HPGUI_Group);
            app.HP3_Tab.Title = 'Hotplate 3';
            app.HP3_Group = uitabgroup(app.HP3_Tab);
            app.Input_3 = uitab(app.HP3_Group);
            app.Input_3.Title = 'Input';
            app.ExperimentalFittingLabel_3 = uilabel(app.Input_3);
            app.ExperimentalFittingLabel_3.Text = 'Experimental/Fitting:';
            app.t_offset_unit_3 = uidropdown(app.Input_3);
            app.t_offset_unit_3.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_3.Value = 'sec';
            app.THKnmEditFieldLabel_3 = uilabel(app.Input_3);
            app.THKnmEditFieldLabel_3.Text = 'THK [nm]';
            app.THK_3 = uieditfield(app.Input_3, 'numeric');
            app.THK_3.Value = 76.96;
            app.HeatTempCLabel_3 = uilabel(app.Input_3);
            app.HeatTempCLabel_3.Text = 'Heat Temp [C]';
            app.TempH_3 = uieditfield(app.Input_3, 'numeric');
            app.TempH_3.Value = 26;
            app.TimeOffsetLabel_3 = uilabel(app.Input_3);
            app.TimeOffsetLabel_3.Text = 'Time Offset';
            app.TimeOffset_3 = uieditfield(app.Input_3, 'numeric');
            app.IterationsMeasureLabel_3 = uilabel(app.Input_3);
            app.IterationsMeasureLabel_3.Text = '# Iterations/Measure';
            app.IterM_3 = uieditfield(app.Input_3, 'numeric');
            app.IterM_3.Value = 1;
            app.PreStressTimeminLabel_3 = uilabel(app.Input_3);
            app.PreStressTimeminLabel_3.Text = 'PreStress Time [min]';
            app.PreBiasTime_3 = uieditfield(app.Input_3, 'numeric');
            app.CoolTempCLabel_3 = uilabel(app.Input_3);
            app.CoolTempCLabel_3.Text = 'Cool Temp [C]';
            app.TempC_3 = uieditfield(app.Input_3, 'numeric');
            app.TempC_3.Value = 26;
            app.P1Lamp_3 = uilamp(app.Input_3);
            app.P2Lamp_3 = uilamp(app.Input_3);
            app.P3Lamp_3 = uilamp(app.Input_3);
            app.P4Lamp_3 = uilamp(app.Input_3);
            app.P1_3 = uibutton(app.Input_3, 'state');
            app.P1_3.ValueChangedFcn = createCallbackFcn(app, @P1_3Changed, true);
            app.P1_3.Text = 'OFF';
            app.P2_3 = uibutton(app.Input_3, 'state');
            app.P2_3.ValueChangedFcn = createCallbackFcn(app, @P2_3Changed, true);
            app.P2_3.Text = 'OFF';
            app.P3_3 = uibutton(app.Input_3, 'state');
            app.P3_3.ValueChangedFcn = createCallbackFcn(app, @P3_3Changed, true);
            app.P3_3.Text = 'OFF';
            app.P4_3 = uibutton(app.Input_3, 'state');
            app.P4_3.ValueChangedFcn = createCallbackFcn(app, @P4_3Changed, true);
            app.P4_3.Text = 'OFF';
            app.ArduinoPinLabel_1_3 = uilabel(app.Input_3);
            app.ArduinoPinLabel_1_3.Text = 'Arduino Pin #:';
            app.AP1_3 = uieditfield(app.Input_3, 'numeric');
            app.AP1_3.Value = 1;
            app.AP2_3 = uieditfield(app.Input_3, 'numeric');
            app.AP2_3.Value = 2;
            app.AP3_3 = uieditfield(app.Input_3, 'numeric');
            app.AP3_3.Value = 3;
            app.AP4_3 = uieditfield(app.Input_3, 'numeric');
            app.AP4_3.Value = 4;
            app.ArduinoPinLabel_2_3 = uilabel(app.Input_3);
            app.ArduinoPinLabel_2_3.Text = 'Arduino Pin #:';
            app.P5Lamp_3 = uilamp(app.Input_3);
            app.P6Lamp_3 = uilamp(app.Input_3);
            app.P7Lamp_3 = uilamp(app.Input_3);
            app.P8Lamp_3 = uilamp(app.Input_3);
            app.P5_3 = uibutton(app.Input_3, 'state');
            app.P5_3.ValueChangedFcn = createCallbackFcn(app, @P5_3Changed, true);
            app.P5_3.Text = 'OFF';
            app.P6_3 = uibutton(app.Input_3, 'state');
            app.P6_3.ValueChangedFcn = createCallbackFcn(app, @P6_3Changed, true);
            app.P6_3.Text = 'OFF';
            app.P7_3 = uibutton(app.Input_3, 'state');
            app.P7_3.ValueChangedFcn = createCallbackFcn(app, @P7_3Changed, true);
            app.P7_3.Text = 'OFF';
            app.P8_3 = uibutton(app.Input_3, 'state');
            app.P8_3.ValueChangedFcn = createCallbackFcn(app, @P8_3Changed, true);
            app.P8_3.Text = 'OFF';
            app.AP5_3 = uieditfield(app.Input_3, 'numeric');
            app.AP5_3.Value = 5;
            app.AP6_3 = uieditfield(app.Input_3, 'numeric');
            app.AP6_3.Value = 6;
            app.AP7_3 = uieditfield(app.Input_3, 'numeric');
            app.AP7_3.Value = 7;
            app.AP8_3 = uieditfield(app.Input_3, 'numeric');
            app.AP8_3.Value = 8;
            app.PinStatesLabel_3 = uilabel(app.Input_3);
            app.PinStatesLabel_3.Text = 'Pin States';
            app.P1Label_3 = uilabel(app.Input_3);
            app.P1Label_3.Text = '1';
            app.P2Label_3 = uilabel(app.Input_3);
            app.P2Label_3.Text = '2';
            app.P3Label_3 = uilabel(app.Input_3);
            app.P3Label_3.Text = '3';
            app.P4Label_3 = uilabel(app.Input_3);
            app.P4Label_3.Text = '4';
            app.P5Label_3 = uilabel(app.Input_3);
            app.P5Label_3.Text = '5';
            app.P6Label_3 = uilabel(app.Input_3);
            app.P6Label_3.Text = '6';
            app.P7Label_3 = uilabel(app.Input_3);
            app.P7Label_3.Text = '7';
            app.P8Label_3 = uilabel(app.Input_3);
            app.P8Label_3.Text = '8';
            app.PinReset_3 = uibutton(app.Input_3, 'push');
            app.PinReset_3.Text = 'Pin Reset';
            app.SwitchAllLabel_3 = uilabel(app.Input_3);
            app.SwitchAllLabel_3.Text = 'Switch All';
            app.SwitchAll_HP3 = uiswitch(app.Input_3, 'toggle');
            app.SwitchAll_HP3.ValueChangedFcn = createCallbackFcn(app, @ToggleAllPinsHP3, true);
            app.SaveDataAsTextAreaLabel_3 = uilabel(app.Input_3);
            app.SaveDataAsTextAreaLabel_3.Text = 'Save Name, unit 3:';
            app.DataFileName_MU3 = uitextarea(app.Input_3);
            app.DataFileName_MU3.Value = {'test_MU3'};
            app.CV1Tab_3 = uitab(app.HP3_Group);
            app.CV1Tab_3.Title = 'CV1';
            app.Export_CV_1_3 = uibutton(app.CV1Tab_3, 'push');
            app.Export_CV_1_3.Text = 'Export Figure';
            app.CV1_3 = uiaxes(app.CV1Tab_3);
            title(app.CV1_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_3, 'Voltage (V)')
            ylabel(app.CV1_3, 'Capacitance (pF)')
            app.Normalize_1_3 = uibutton(app.CV1Tab_3, 'state');
            app.Normalize_1_3.Text = 'Normalize';
            app.CV2_3 = uiaxes(app.CV1Tab_3);
            title(app.CV2_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_3, 'Voltage (V)')
            ylabel(app.CV2_3, 'Capacitance (pF)')
            app.CV3_3 = uiaxes(app.CV1Tab_3);
            title(app.CV3_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_3, 'Voltage (V)')
            ylabel(app.CV3_3, 'Capacitance (pF)')
            app.CV4_3 = uiaxes(app.CV1Tab_3);
            title(app.CV4_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_3, 'Voltage (V)')
            ylabel(app.CV4_3, 'Capacitance (pF)')
            app.CV2Tab_3 = uitab(app.HP3_Group);
            app.CV2Tab_3.Title = 'CV2';
            app.Export_CV_2_3 = uibutton(app.CV2Tab_3, 'push');
            app.Export_CV_2_3.Text = 'Export Figure';
            app.CV5_3 = uiaxes(app.CV2Tab_3);
            title(app.CV5_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_3, 'Voltage (V)')
            ylabel(app.CV5_3, 'Capacitance (pF)')
            app.Normalize_2_3 = uibutton(app.CV2Tab_3, 'state');
            app.Normalize_2_3.Text = 'Normalize';
            app.CV6_3 = uiaxes(app.CV2Tab_3);
            title(app.CV6_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_3, 'Voltage (V)')
            ylabel(app.CV6_3, 'Capacitance (pF)')
            app.CV7_3 = uiaxes(app.CV2Tab_3);
            title(app.CV7_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_3, 'Voltage (V)')
            ylabel(app.CV7_3, 'Capacitance (pF)')
            app.CV8_3 = uiaxes(app.CV2Tab_3);
            title(app.CV8_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_3, 'Voltage (V)')
            ylabel(app.CV8_3, 'Capacitance (pF)')
            app.VFBFit1Tab_3 = uitab(app.HP3_Group);
            app.VFBFit1Tab_3.Title = 'VFB Fit 1';
            app.byC2_1_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_1_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_3, 'Voltage (V)')
            ylabel(app.byC2_1_3, 'd^2(1/C^2)/d^2V')
            app.byC2_2_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_2_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_3, 'Voltage (V)')
            ylabel(app.byC2_2_3, 'd^2(1/C^2)/d^2V')
            app.byC2_3_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_3_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_3, 'Voltage (V)')
            ylabel(app.byC2_3_3, 'd^2(1/C^2)/d^2V')
            app.byC2_4_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_4_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_3, 'Voltage (V)')
            ylabel(app.byC2_4_3, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_1_3 = uibutton(app.VFBFit1Tab_3, 'push');
            app.Export_byC2_1_3.Text = 'Export Figure';
            app.DerPeaks_3_1 = uibutton(app.VFBFit1Tab_3, 'state');
            app.DerPeaks_3_1.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_3_1Pressed, true);
            app.DerPeaks_3_1.Text = 'Derivative Peaks';
            app.DerPeaks_3_1.Value = true;
            app.UpdateFit_1_3 = uibutton(app.VFBFit1Tab_3, 'push');
            app.UpdateFit_1_3.Text = 'Update Fit';
            app.CFitMinEditFieldLabel_5 = uilabel(app.VFBFit1Tab_3);
            app.CFitMinEditFieldLabel_5.Text = 'C Fit Min:';
            app.CFitMin_1_3 = uieditfield(app.VFBFit1Tab_3, 'numeric');
            app.CFitMin_1_3.Value = 5;
            app.FitRegion_1_3 = uibutton(app.VFBFit1Tab_3, 'state');
            app.FitRegion_1_3.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_5 = uilabel(app.VFBFit1Tab_3);
            app.CFitMaxEditFieldLabel_5.Text = 'C Fit Max:';
            app.CFitMax_1_3 = uieditfield(app.VFBFit1Tab_3, 'numeric');
            app.CFitMax_1_3.Value = 12;
            app.SmoothCheckBox_5 = uicheckbox(app.VFBFit1Tab_3);
            app.SmoothCheckBox_5.Text = 'Smooth';
            app.VFBFit2Tab_3 = uitab(app.HP3_Group);
            app.VFBFit2Tab_3.Title = 'VFB Fit 2';
            app.byC2_5_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_5_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_3, 'Voltage (V)')
            ylabel(app.byC2_5_3, 'd^2(1/C^2)/d^2V')
            app.byC2_6_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_6_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_3, 'Voltage (V)')
            ylabel(app.byC2_6_3, 'd^2(1/C^2)/d^2V')
            app.byC2_7_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_7_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_3, 'Voltage (V)')
            ylabel(app.byC2_7_3, 'd^2(1/C^2)/d^2V')
            app.byC2_8_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_8_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_3, 'Voltage (V)')
            ylabel(app.byC2_8_3, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_3 = uibutton(app.VFBFit2Tab_3, 'push');
            app.Export_byC2_2_3.Text = 'Export Figure';
            app.DerPeaks_3_2 = uibutton(app.VFBFit2Tab_3, 'state');
            app.DerPeaks_3_2.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_3_2Pressed, true);
            app.DerPeaks_3_2.Text = 'Derivative Peaks';
            app.DerPeaks_3_2.Value = true;
            app.SmoothCheckBox_6 = uicheckbox(app.VFBFit2Tab_3);
            app.SmoothCheckBox_6.Text = 'Smooth';
            app.UpdateFit_2_3 = uibutton(app.VFBFit2Tab_3, 'push');
            app.UpdateFit_2_3.Text = 'Update Fit';
            app.FitRegion_2_3 = uibutton(app.VFBFit2Tab_3, 'state');
            app.FitRegion_2_3.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_6 = uilabel(app.VFBFit2Tab_3);
            app.CFitMaxEditFieldLabel_6.Text = 'C Fit Max:';
            app.CFitMax_2_3 = uieditfield(app.VFBFit2Tab_3, 'numeric');
            app.CFitMax_2_3.Value = 12;
            app.CFitMinEditFieldLabel_6 = uilabel(app.VFBFit2Tab_3);
            app.CFitMinEditFieldLabel_6.Text = 'C Fit Min:';
            app.CFitMin_5 = uieditfield(app.VFBFit2Tab_3, 'numeric');
            app.CFitMin_5.Value = 5;
            app.ItTab_3 = uitab(app.HP3_Group);
            app.ItTab_3.Title = 'I-t';
            app.It_3 = uiaxes(app.ItTab_3);
            title(app.It_3, 'Current Vs. Time')
            xlabel(app.It_3, 'Time (hr)')
            ylabel(app.It_3, 'Current (A)')
            app.Export_It_3 = uibutton(app.ItTab_3, 'push');
            app.Export_It_3.Text = 'Export Figure';
            app.VFBvsTimeTab_3 = uitab(app.HP3_Group);
            app.VFBvsTimeTab_3.Title = 'VFB vs. Time';
            app.ExportVFBtime_3 = uibutton(app.VFBvsTimeTab_3, 'push');
            app.ExportVFBtime_3.Text = 'Export Figure';
            app.VFBtime_3 = uiaxes(app.VFBvsTimeTab_3);
            title(app.VFBtime_3, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_3, 'Time')
            ylabel(app.VFBtime_3, 'Flatband Voltage (V)')
            app.TempvsTimeTab_3 = uitab(app.HP3_Group);
            app.TempvsTimeTab_3.Title = 'Temperature vs. Time';
            app.TempTime_3 = uiaxes(app.TempvsTimeTab_3);
            title(app.TempTime_3, 'Temperature Vs. Time')
            xlabel(app.TempTime_3, 'Time (hr)')
            ylabel(app.TempTime_3, 'Temperature (C)')
            app.Export_TempTime_3 = uibutton(app.TempvsTimeTab_3, 'push');
            app.Export_TempTime_3.Text = 'Export Figure';
            app.SaveDataAsTextAreaLabel = uilabel(app.UIFigure);
            app.SaveDataAsTextAreaLabel.Text = 'Save Name:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InSitu_BST_SoftwareV8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end